classdef app_for_lightsheet_V2_test_withfixedpos_withoutcalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ShutterButton                   matlab.ui.control.StateButton
        LightwavelightDropDownLabel     matlab.ui.control.Label
        Lightwavelight                  matlab.ui.control.DropDown
        LaserPowermWEditFieldLabel      matlab.ui.control.Label
        LaserPower                      matlab.ui.control.NumericEditField
        galvo_XSliderLabel              matlab.ui.control.Label
        galvo_XSlider                   matlab.ui.control.Slider
        galvo_YSliderLabel              matlab.ui.control.Label
        galvo_YSlider                   matlab.ui.control.Slider
        Reset_galvo_XYButton            matlab.ui.control.Button
        EnableCam                       matlab.ui.control.Button
        DisableCam                      matlab.ui.control.Button
        ImagecontrastLabel              matlab.ui.control.Label
        Imagecontrast                   matlab.ui.control.NumericEditField
        FilterwheelDropDownLabel        matlab.ui.control.Label
        Filterwheel                     matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        SaveImage                       matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Updown_stepDropDownLabel        matlab.ui.control.Label
        Updown_stepDropDown             matlab.ui.control.DropDown
        Inout_stepDropDownLabel         matlab.ui.control.Label
        Inout_stepDropDown              matlab.ui.control.DropDown
        Leftright_stepDropDownLabel     matlab.ui.control.Label
        Leftright_stepDropDown          matlab.ui.control.DropDown
        Rotation_stepDropDownLabel      matlab.ui.control.Label
        Rotation_stepDropDown           matlab.ui.control.DropDown
        LIghtpathDropDownLabel          matlab.ui.control.Label
        LIghtpathDropDown               matlab.ui.control.DropDown
        UITable_stage                   matlab.ui.control.Table
        UITable_path                    matlab.ui.control.Table
        UITable_motor                   matlab.ui.control.Table
        UITable_afg                     matlab.ui.control.Table
        AFG_scanButton                  matlab.ui.control.StateButton
        ExitButton                      matlab.ui.control.Button
        SaveImagetwopathButton          matlab.ui.control.Button
        ImagesavingdirectoryButton      matlab.ui.control.Button
        savepathEditFieldLabel          matlab.ui.control.Label
        savepathEditField               matlab.ui.control.EditField
        TakingimagesononetilePanel      matlab.ui.container.Panel
        StateofCheckPointsEditFieldLabel  matlab.ui.control.Label
        StateofCheckPointsEditField     matlab.ui.control.EditField
        saveButton                      matlab.ui.control.Button
        saveButton_2                    matlab.ui.control.Button
        Start_posEditFieldLabel         matlab.ui.control.Label
        Start_posEditField              matlab.ui.control.NumericEditField
        End_posEditFieldLabel           matlab.ui.control.Label
        End_posEditField                matlab.ui.control.NumericEditField
        Check_points1x5Label            matlab.ui.control.Label
        Check_points                    matlab.ui.control.NumericEditField
        movecheckpointsSpinnerLabel     matlab.ui.control.Label
        movecheckpointsSpinner          matlab.ui.control.Spinner
        saveCheckpointsButton           matlab.ui.control.Button
        takeonetileimagesButton         matlab.ui.control.Button
        z_distanceumLabel               matlab.ui.control.Label
        zstack_distanceum               matlab.ui.control.NumericEditField
        TakingoneimagesonMbyNtilesPanel  matlab.ui.container.Panel
        Vertiles_numEditFieldLabel      matlab.ui.control.Label
        Vertiles_num                    matlab.ui.control.NumericEditField
        Horztiles_numEditFieldLabel     matlab.ui.control.Label
        Horztiles_num                   matlab.ui.control.NumericEditField
        MoveandStitch_2DButton          matlab.ui.control.Button
        TakingimagesonMbyNtileswithzstacksPanel  matlab.ui.container.Panel
        VerticaltileEditFieldLabel      matlab.ui.control.Label
        VerticaltileEditField           matlab.ui.control.NumericEditField
        HorizontaltileEditFieldLabel    matlab.ui.control.Label
        HorizontaltileEditField         matlab.ui.control.NumericEditField
        CheckPointsEditFieldLabel       matlab.ui.control.Label
        CheckPointsEditField            matlab.ui.control.NumericEditField
        startofeachtileEditFieldLabel   matlab.ui.control.Label
        startofeachtileEditField        matlab.ui.control.NumericEditField
        saveButton_3                    matlab.ui.control.Button
        endofeachtileEditFieldLabel     matlab.ui.control.Label
        endofeachtileEditField          matlab.ui.control.NumericEditField
        saveButton_4                    matlab.ui.control.Button
        createscanpatterncenterButton   matlab.ui.control.Button
        W488nmCheckBox                  matlab.ui.control.CheckBox
        W561nmCheckBox                  matlab.ui.control.CheckBox
        W640nmCheckBox                  matlab.ui.control.CheckBox
        ChoosewhichcolortoimageLabel    matlab.ui.control.Label
        DeepLabel_2                     matlab.ui.control.Label
        SurfaceLabel                    matlab.ui.control.Label
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        GreensignalwillblockmotionexceptinzaxisLabel  matlab.ui.control.Label
        TileStatesTextAreaLabel         matlab.ui.control.Label
        TileStatesTextArea              matlab.ui.control.TextArea
        StartImageButton                matlab.ui.control.Button
        SetoffsetofdetectionlensButton  matlab.ui.control.Button
        ExcitionLensandDetectionLensPanel  matlab.ui.container.Panel
        leftButtonExt                   matlab.ui.control.Button
        rightButtonExt                  matlab.ui.control.Button
        leftButtonObj                   matlab.ui.control.Button
        rightButtonObj                  matlab.ui.control.Button
        ExtStepsizeDropDownLabel        matlab.ui.control.Label
        ExtStepsizeDropDown             matlab.ui.control.DropDown
        ObjStepsizeDropDownLabel        matlab.ui.control.Label
        ObjStepsizeDropDown             matlab.ui.control.DropDown
        PaintModePanel                  matlab.ui.container.Panel
        PaintButton                     matlab.ui.control.StateButton
        UpButton                        matlab.ui.control.Button
        DownButton                      matlab.ui.control.Button
        LeftButton                      matlab.ui.control.Button
        RightButton                     matlab.ui.control.Button
        RollSliderLabel                 matlab.ui.control.Label
        RollSlider                      matlab.ui.control.Slider
        RollScanButton                  matlab.ui.control.StateButton
        testboundaryButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        
         % Description
        
        f;
        
        paint;
        paint_initx;paint_inity;paint_initz;
        paint_init_image;
        allLasers;
        myhandles;
        counter;
        saveImage;
        saveImage_twopath;
        Stage_step_X;
        Stage_step_Y;
        Stage_step_Z;
        Stage_step_T;
        AFG_step;
        AFG_voltage1;AFG_voltage2;
    end
    
    properties (Access = public)
        % Description
        % two path galvo voltage
        galvo_X1voltage;
        galvo_Y1voltage;
        galvo_Z1voltage;
        
        galvo_X2voltage;
        galvo_Y2voltage;
        galvo_Z2voltage;
        
        % 
        % position of ext1,ext2,det
        ExtLens1_pos;ExtLens2_pos;DetLens_pos;
        
        % position of table
        state_path;state_motor;state_stage;state_afg;state_offset;
        
        % voltage of AFG
        afg1_offset,afg1_top,afg1_bottom;
        afg2_offset,afg2_top,afg2_bottom;
        % intensity profile
        draw_intensity_profile;
        
        expousreTime;
        stepSizeExt;
        stepSizeObj;
        imagecontrast_change;
        current_path;
        controlParameters;
        Shutterstate1;
        Shutterstate2;
        checkpoints_valuelist;
        checkpoints_index;
        zstacksposs;
        xinitPos;yinitPos;zinitPos;
        xinitPos1;yinitPos1;zinitPos1;
        xinitGalvo;yinitGalvo;zinitGalvo;   
        start_3D;
        start_correct;
        allcheckpoints;
        xyzScanArray;
        scanrow;scancol;scanindex;
        dirname;
        waitbar;
        wait_status;
        checkpoints_onetile;onetile_index;
        checkpoints_zpos;
        checkpoints_multipletile;tile_index;
        xyzScanArray_status
        offset_detectionlens;
        offset_detectionlens_488nm;
        offset_detectionlens_561nm;
        offset_detectionlens_640nm;
        status_488nm;status_561nm;status_640nm;
        a;
    end
    
    methods (Access = private)
        
        function Image = requestoneimagefromcamera(app)
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',10);
            
            % request an image
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                if app.controlParameters.MMC.getRemainingImageCount()>0
                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                    width = app.controlParameters.MMC.getImageWidth();
                    height = app.controlParameters.MMC.getImageHeight();
                    pixelType = 'uint16';
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    Image = rot90(image);                    
                    break;
                 end
            end
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
        end
        
        function [start_tile,end_tile] = getboundary(app)

            image_content_vector = [];
            image_pos_vector = [];
            initial_step = 200;
            current_y = app.controlParameters.MMC.getYPosition();%22000
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,12800-current_y);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            current_y = app.controlParameters.MMC.getYPosition();
            %move to start position
            % start pos is 6800 end pos is 20000
            startpos=5200;endpos=15000;
            if current_y - initial_step < startpos
                f=msgbox('Reach the limit.','warning');
                return;
            end
            if current_y + initial_step > endpos
                f=msgbox('Reach the limit.','warning');
                return;
            end
            outputSingleScan(app.controlParameters.s1,[4,0,0,0]);
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,startpos-current_y);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            % turn on the laser.
            SwitchOnOffObis(app.allLasers(1).serialPort,0);
            SwitchOnOffObis(app.allLasers(2).serialPort,1);
            SwitchOnOffObis(app.allLasers(3).serialPort,0);
            fprintf(app.controlParameters.sFWDet, 'pos=3');
            pause(10);
            % start go through from deep to surface (6600 to 20000) 
            for i = 1:100
                current_y = app.controlParameters.MMC.getYPosition();
                if current_y + initial_step > endpos
                    break;
                end
                Image = requestoneimagefromcamera(app);
                %imwrite(Image,['testimages/','num2str(i)','.tiff'])
                if var(single(Image(:)))>15
                    image_content_vector = [image_content_vector,1];
                    image_pos_vector = [image_pos_vector,current_y];
                    %imwrite(Image,['testimages/',num2str(i),'_1_',num2str(var(single(Image(:)))),'.tiff'])
                else
                    image_content_vector = [image_content_vector,0];
                    image_pos_vector = [image_pos_vector,current_y];
                    %imwrite(Image,['testimages/',num2str(i),'_0_',num2str(var(single(Image(:)))),'.tiff'])
                end
                app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,200);
                app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            end
            %return;
            %image_content_vector =
            % output the boundary of sample on this tile
            if sum(image_content_vector) == 0
                start_tile = 11600;
                end_tile = 11800;
                return;
            elseif sum(image_content_vector) == 1
                middle_tile = int32(image_pos_vector(image_content_vector>0));
            elseif sum(image_content_vector) > 1
                index = find(image_content_vector>0);
                start_pos = image_pos_vector(index(1));
                end_pos = image_pos_vector(index(end));
                middle_tile = int32((start_pos+end_pos)/2);
            end
            image_pos_vector(image_content_vector>0); 
            % find the start pos and end pos for each tile
            % find start pos
            index = find(image_content_vector>0);
            if index(1)==1
                start_tile=startpos;
            else
                pos1 = image_pos_vector(index(1));pos2=image_pos_vector(index(1)-1);
                for i=1:8
                    mid_pos = round((pos1+pos2)/2);
                    current_y = app.controlParameters.MMC.getYPosition();
                    app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,mid_pos-current_y);
                    app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
                    Image = requestoneimagefromcamera(app);
                    current_y = app.controlParameters.MMC.getYPosition();
                    if var(single(Image(:)))>15
                        pos1 = round(current_y);
                    else
                        pos2 = round(current_y);
                    end
                end
                start_tile = round(pos2);
            end
            
            % find end pos
            if index(end)==length(image_content_vector)
                end_tile = endpos;
            else
                pos1 = image_pos_vector(index(end));pos2 = image_pos_vector(index(end)+1);
                for i=1:8
                    mid_pos = round((pos1+pos2)/2);
                    current_y = app.controlParameters.MMC.getYPosition();
                    app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,mid_pos-current_y);
                    app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
                    Image = requestoneimagefromcamera(app);
                    current_y = app.controlParameters.MMC.getYPosition();
                    if var(single(Image(:)))>15
                        pos1 = round(current_y);
                    else
                        pos2 = round(current_y);
                    end
                end
                end_tile = round(pos2);
            end
            if end_tile-start_tile < 110
                start_tile = start_tile-60;
                end_tile = end_tile+60; 
            end
        end
    end
    
    methods (Access = public)
        
        function results = calibration_eachtile(app)
        
            %app.waitbar = uifigure;
            %app.wait_status = uiprogressdlg(app.waitbar,'Title','Calibration','Message','Start calibration','Cancelable','on');
            %dx = app.xyzScanArray(1,app.tile_index) - xPos; dy = app.xyzScanArray(2,app.tile_index) - yPos; dz = app.xyzScanArray(3,app.tile_index) - zPos;
            %SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
            
            for current_tile =1:size(app.xyzScanArray,2)
                disp(['currenttile: ',num2str(current_tile)]);
                [xPos, yPos, zPos] = getXYZposition(app.controlParameters.MMC);
                dx = app.xyzScanArray(1,current_tile) - xPos; dy = 0; dz = app.xyzScanArray(3,current_tile) - zPos;
                SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
                %[startpos,endpos] = getboundary(app);
                startpos = app.startofeachtileEditField.Value;
                endpos = app.endofeachtileEditField.Value;
                for j=1:app.CheckPointsEditField.Value
                    app.checkpoints_multipletile(current_tile,j).startpos = round(startpos,-1);
                    app.checkpoints_multipletile(current_tile,j).endpos = round(endpos,-1);
                end
                
                %do the calibration'
                if startpos == 11600 && endpos == 11800 % no checkpoints needed
                    z_pos = round(linspace(round(app.checkpoints_multipletile(current_tile,1).startpos,-1)+50,round(app.checkpoints_multipletile(current_tile,1).endpos,-1)-50,app.CheckPointsEditField.Value));
                    if app.W488nmCheckBox.Value==1
                        for i = 1:app.CheckPointsEditField.Value
                            app.checkpoints_multipletile(current_tile,i).Detectionlens_488nm = 0;
                            app.checkpoints_multipletile(current_tile,i).yPos_488nm = z_pos(i);
                            app.checkpoints_multipletile(current_tile,i).current_path = app.current_path;
                        end
                    end
                    
                    if app.W561nmCheckBox.Value==1
                        for i = 1:app.CheckPointsEditField.Value
                            app.checkpoints_multipletile(current_tile,i).Detectionlens_561nm = 0;
                            app.checkpoints_multipletile(current_tile,i).yPos_561nm = z_pos(i);
                            app.checkpoints_multipletile(current_tile,i).current_path = app.current_path;
                        end
                    end
                    
                    if app.W640nmCheckBox.Value==1
                        for i = 1:app.CheckPointsEditField.Value
                            app.checkpoints_multipletile(current_tile,i).Detectionlens_640nm = 0;
                            app.checkpoints_multipletile(current_tile,i).yPos_640nm = z_pos(i);
                            app.checkpoints_multipletile(current_tile,i).current_path = app.current_path;
                        end
                    end
                    
                else
                    z_pos = round(linspace(round(app.checkpoints_multipletile(current_tile,1).startpos,-1)+50,round(app.checkpoints_multipletile(current_tile,1).endpos,-1)-50,app.CheckPointsEditField.Value));
                    if app.W488nmCheckBox.Value==1
                        SwitchOnOffObis(app.allLasers(1).serialPort,1);
                        SwitchOnOffObis(app.allLasers(2).serialPort,0);
                        SwitchOnOffObis(app.allLasers(3).serialPort,0);
                        fprintf(app.controlParameters.sFWDet, 'pos=2');
                        pause(10);
                        for i = 1:app.CheckPointsEditField.Value
                            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                            dy = z_pos(i) - yPos;
                            SetRelativePos(app.controlParameters.MMC,0,dy,0);
                            % move detection lens to different position [-0.05.0.05]
                            % step size = 0.01
                            image_score = [];
                            detect_pos = [-0.06:0.01:0.06];
                            for j = 1:length(detect_pos)
                                fprintf(app.controlParameters.sDetLens,['1PA',num2str(detect_pos(j)+app.offset_detectionlens_488nm)]);
                                pause(0.5);
                                Image = requestoneimagefromcamera(app);
                                image_score = [image_score,TENV(app,Image)];
                            end
                            [score,max_index]=max(image_score);
                            app.checkpoints_multipletile(current_tile,i).Detectionlens_488nm = detect_pos(max_index)+app.offset_detectionlens_488nm;
                            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                            %app.checkpoints_multipletile(current_tile,i).xPos = xPos;
                            app.checkpoints_multipletile(current_tile,i).yPos_488nm = yPos;
                            %app.checkpoints_multipletile(current_tile,i).zPos = zPos;
                            app.checkpoints_multipletile(current_tile,i).current_path = app.current_path;
                        end
                    end
                    if app.W561nmCheckBox.Value==1
                        SwitchOnOffObis(app.allLasers(1).serialPort,0);
                        SwitchOnOffObis(app.allLasers(2).serialPort,1);
                        SwitchOnOffObis(app.allLasers(3).serialPort,0);
                        fprintf(app.controlParameters.sFWDet, 'pos=3');
                        pause(10);
                        for i = 1:app.CheckPointsEditField.Value
                            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                            dy = z_pos(i) - yPos;
                            SetRelativePos(app.controlParameters.MMC,0,dy,0);
                            % move detection lens to different position [-0.05.0.05]
                            % step size = 0.01
                            image_score = [];
                            disp(i);
                            detect_pos = [-0.06:0.01:0.06];
                            for j = 1:length(detect_pos)
                                fprintf(app.controlParameters.sDetLens,['1PA',num2str(detect_pos(j)+app.offset_detectionlens_561nm)]);
                                pause(0.5);
                                Image = requestoneimagefromcamera(app);
                                image_score = [image_score,TENV(app,Image)];
                            end
                            [score,max_index]=max(image_score);
                            app.checkpoints_multipletile(current_tile,i).Detectionlens_561nm = detect_pos(max_index)+app.offset_detectionlens_561nm;
                            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                            %app.checkpoints_multipletile(current_tile,i).xPos = xPos;
                            app.checkpoints_multipletile(current_tile,i).yPos_561nm = yPos;
                            %app.checkpoints_multipletile(current_tile,i).zPos = zPos;
                            app.checkpoints_multipletile(current_tile,i).current_path = app.current_path;
                        end
                    end
                    if app.W640nmCheckBox.Value==1
                        SwitchOnOffObis(app.allLasers(1).serialPort,0);
                        SwitchOnOffObis(app.allLasers(2).serialPort,0);
                        SwitchOnOffObis(app.allLasers(3).serialPort,1);
                        fprintf(app.controlParameters.sFWDet, 'pos=4');
                        pause(10);
                        for i = 1:app.CheckPointsEditField.Value
                            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                            dy = z_pos(i) - yPos;
                            SetRelativePos(app.controlParameters.MMC,0,dy,0);
                            % move detection lens to different position [-0.05.0.05]
                            % step size = 0.01
                            image_score = [];
                            detect_pos = [-0.06:0.01:0.06];
                            for j = 1:length(detect_pos)
                                fprintf(app.controlParameters.sDetLens,['1PA',num2str(detect_pos(j)+app.offset_detectionlens_640nm)]);
                                pause(0.5);
                                Image = requestoneimagefromcamera(app);
                                image_score = [image_score,TENV(app,Image)];
                            end
                            [score,max_index]=max(image_score);
                            app.checkpoints_multipletile(current_tile,i).Detectionlens_640nm = detect_pos(max_index)+app.offset_detectionlens_640nm;
                            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                            %app.checkpoints_multipletile(current_tile,i).xPos = xPos;
                            app.checkpoints_multipletile(current_tile,i).yPos_640nm = yPos;
                            %app.checkpoints_multipletile(current_tile,i).zPos = zPos;
                            app.checkpoints_multipletile(current_tile,i).current_path = app.current_path;
                        end
                    end
                    
                end
                
            end


        end
        
        function results = TENV(app,image)
            Sx = fspecial('sobel');
            Gx = imfilter(double(image),Sx,'replicate','conv');
            Gy = imfilter(double(image),Sx,'replicate','conv');
            G = Gx.^2 + Gy.^2;
            results = std2(G)^2/2048/2048;            
        end
        
        function results = calibration_once(app)
            if app.status_488nm==true
                app.offset_detectionlens = app.offset_detectionlens_488nm;
            elseif app.status_561nm==true
                app.offset_detectionlens = app.offset_detectionlens_561nm;
            elseif app.status_640nm==true
                app.offset_detectionlens = app.offset_detectionlens_640nm;
            end
            detect_pos = [-0.04:0.01:0.04];
            image_score = [];
            for j=1:length(detect_pos)
                fprintf(app.controlParameters.sDetLens,['1PA',num2str(detect_pos(j)+app.offset_detectionlens)]);
                pause(0.5)
                Image = requestoneimagefromcamera(app);
                image_score = [image_score,TENV(app,Image)];
            end
            [score,max_index]=max(image_score);
            fprintf(app.controlParameters.sDetLens,['1PA',num2str(detect_pos(max_index)+app.offset_detectionlens)]);
            disp(['offset: ',num2str(app.offset_detectionlens),' pos_det: ',num2str(detect_pos(max_index)+app.offset_detectionlens)])
            results=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('D:\lightSheetsourcecode_twopath\AfgCode','D:\lightSheetsourcecode_twopath\DCMotor',...
    'D:\lightSheetsourcecode_twopath\FilterWheel','D:\lightSheetsourcecode_twopath\OBISGeneral',...
    'D:\lightSheetsourcecode_twopath\UsefullFunctions\CalibrationFunctions','D:\lightSheetsourcecode_twopath\UsefullFunctions\PickTiles',...
    'D:\lightSheetsourcecode_twopath\MMcodeOrcaV2\selfmade',...
    'D:\lightSheetsourcecode_twopath\MMcodeOrcaV2\wholezstackfiles');
            app.StatusLamp.Color = [1 0 0];
            app.controlParameters = evalin('base','controlParameters');
            app.allLasers = evalin('base','allLasers');
            app.current_path = 1;
            app.draw_intensity_profile = false;
            
            SwitchOnOffObis(app.allLasers(1).serialPort,0);
            SwitchOnOffObis(app.allLasers(2).serialPort,0);
            SwitchOnOffObis(app.allLasers(3).serialPort,0);
            
            app.state_stage = readtable('states_record.xlsx','Sheet',1);
            app.state_path = readtable('states_record.xlsx','Sheet',2);
            app.state_motor = readtable('states_record.xlsx','Sheet',3);
            app.state_afg = readtable('states_record.xlsx','Sheet',4);
            app.state_offset = readtable('states_record.xlsx','Sheet',5);
            
            
            app.galvo_X1voltage = 0;app.galvo_X2voltage = 0;
            app.galvo_Y1voltage = 0;app.galvo_Y2voltage = 0;
            app.galvo_Z1voltage = 0;app.galvo_Z2voltage = 0;
            app.expousreTime = 100;
            app.stepSizeExt = 0.1;
            app.stepSizeObj = 0.01;
            app.Shutterstate1=0;app.Shutterstate2=0;
            
            app.imagecontrast_change = true;
            app.expousreTime = 100;%ms
            app.saveImage = false;
            app.saveImage_twopath = false;
            
            app.Stage_step_X = 10;
            app.Stage_step_Y = 10;
            app.Stage_step_Z = 10;
            app.Stage_step_T = 10;
            
            %app.X_pos.Value = app.controlParameters.MMC.getXPosition();
            %app.Y_pos.Value = app.controlParameters.MMC.getYPosition();
            %app.Z_pos.Value = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            %app.T_pos.Value = app.controlParameters.MMC.getPosition('ZStage:T:32');
            
            app.state_stage.X = app.controlParameters.MMC.getXPosition();
            app.state_stage.Y = app.controlParameters.MMC.getYPosition();
            app.state_stage.Z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.state_stage.T = app.controlParameters.MMC.getPosition('ZStage:T:32');
            
            app.AFG_step = 1;
            app.afg1_offset = app.state_afg.Offset_mv_(2);app.afg2_offset = app.state_afg.Offset_mv_(2);            
            app.afg1_top = app.state_afg.Top_mv_(1);app.afg2_top = app.state_afg.Top_mv_(2);            
            app.afg1_bottom = app.state_afg.Bottom_mv_(1);app.afg2_bottom = app.state_afg.Bottom_mv_(2);
            
            %app.afg1_middle = 0;app.afg2_middle = 0;
            fwrite(app.controlParameters.afg, ':source1:function DC');
            fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str(app.afg1_offset),'mv']);
            fwrite(app.controlParameters.afg, ':output1 on;');
            
            fwrite(app.controlParameters.afg, ':source2:function DC');
            fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.afg2_offset),'mv']);
            fwrite(app.controlParameters.afg, ':output2 on;');
            
            
            %dcValueAfterWrite1 = query(app.controlParameters.afg, ':source1:voltage:offset?');
            %dcValueAfterWrite2 = query(app.controlParameters.afg, ':source2:voltage:offset?');
            %state_table.X{6} =  dcValueAfterWrite1;state_table.X{7} =  dcValueAfterWrite2;


            %app.Z_stack.Value = 1;
            % get the position of ext and obj lens
            
            fprintf(app.controlParameters.sExtLens1,'1TP?');
            pos = fscanf(app.controlParameters.sExtLens1);
            app.ExtLens1_pos = getpos(pos);
            app.state_motor.MotorPosition_mm_(1) = str2num(app.ExtLens1_pos);
            
            %app.ExtPos.Value = str2num(pos);
            app.dirname = '';
            
            fprintf(app.controlParameters.sExtLens2,'1TP?');
            pos = fscanf(app.controlParameters.sExtLens2);
            app.ExtLens2_pos = getpos(pos);
            app.state_motor.MotorPosition_mm_(2) = str2num(app.ExtLens2_pos);
            
            fprintf(app.controlParameters.sDetLens,'1TP?');
            pos = fscanf(app.controlParameters.sDetLens);
            app.DetLens_pos = getpos(pos);
            app.state_motor.MotorPosition_mm_(3) = str2num(app.DetLens_pos);
            
            %app.ObjPos.Value = str2num(pos);
            app.checkpoints_valuelist=zeros(app.Check_points.Value,2);
            app.start_3D = false;
            app.start_correct = false;
            
            
            app.UITable_stage.Data = app.state_stage;
            app.UITable_path.Data = app.state_path;
            app.UITable_motor.Data = app.state_motor;
            app.UITable_afg.Data = app.state_afg;
            %app.UITable_offset.Data = app.state_offset;
            
            % set the framerate of camera
            app.controlParameters.MMC.stopSequenceAcquisition;
            %expTimeForScan = 100;
            %app.controlParameters.MMC.setProperty(app.controlParameters.cameraLabel,'Exposure', expTimeForScan);
            %app.controlParameters.MMC.startSequenceAcquisition(5, 0, false);
            %Value = 1/(str2num(app.controlParameters.MMC.getProperty(app.controlParameters.cameraLabel, 'Exposure'))/1000);
            
            app.controlParameters.MMC.clearCircularBuffer;
            app.onetile_index = 0;
            %app.waitbar = app.waitbar;
            %d = uiprogressdlg(app.waitbar,'Title','please wait','Message','open it');

            app.TileStatesTextArea.Value{1} = '';
            app.TileStatesTextArea.Value{2} = '';
            app.TileStatesTextArea.Value{3} = '';
            
            app.xyzScanArray_status = false;
            app.offset_detectionlens = 0;
            app.offset_detectionlens_488nm = 0;
            app.offset_detectionlens_561nm = 0;
            app.offset_detectionlens_640nm = 0;
            app.status_488nm=false;app.status_561nm=false;app.status_640nm=false;
            %app.StateofTilesEditField.Value = num2str(a);
            %app.waitbar = uifigure;
            % keep the record of max/min z max/min x max/min y
            % y_near_the camera 6420um
            % y_farfrom_the camera 22000um
            % x_top -19500um
            % x_bottom 6200um
            % z right -21500
            % z left 
        end

        % Value changed function: ShutterButton
        function ShutterButtonValueChanged(app, event)
            app.Lightwavelight.Value
            value = app.ShutterButton.Value;
            if app.current_path == 1
                if value == 1
                    app.Shutterstate1 = 4;
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                else
                    app.Shutterstate1 = 0;
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                end
            elseif app.current_path == 2
                if value == 1
                    app.Shutterstate2 = 4;
                    outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
                else
                    app.Shutterstate2 = 0;
                    outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
                end
            end
        end

        % Value changed function: Lightwavelight
        function LightwavelightValueChanged(app, event)
            value = app.Lightwavelight.Value;
            
                if convertCharsToStrings(value) == 'None'
                    SwitchOnOffObis(app.allLasers(1).serialPort,0);
                    SwitchOnOffObis(app.allLasers(2).serialPort,0);
                    SwitchOnOffObis(app.allLasers(3).serialPort,0);
                elseif convertCharsToStrings(value) == '488nm'
                    SwitchOnOffObis(app.allLasers(1).serialPort,1);
                    SwitchOnOffObis(app.allLasers(2).serialPort,0);
                    SwitchOnOffObis(app.allLasers(3).serialPort,0);
                    %fprintf(app.controlParameters.sFWDet,'pos=1');
                elseif convertCharsToStrings(value) == '561nm'
                    SwitchOnOffObis(app.allLasers(1).serialPort,0);
                    SwitchOnOffObis(app.allLasers(2).serialPort,1);
                    SwitchOnOffObis(app.allLasers(3).serialPort,0);
                    %fprintf(app.controlParameters.sFWDet,'pos=1');
                elseif convertCharsToStrings(value) == '640nm'
                    SwitchOnOffObis(app.allLasers(1).serialPort,0);
                    SwitchOnOffObis(app.allLasers(2).serialPort,0);
                    SwitchOnOffObis(app.allLasers(3).serialPort,1);
                    %fprintf(app.controlParameters.sFWDet,'pos=1');
                end                
            
            
        end

        % Value changed function: LaserPower
        function LaserPowerValueChanged(app, event)
            value = app.LaserPower.Value;
            if value > 15
                value = 15;
            elseif value < 0
                value = 0;
            end
            for ii = 1:numel(app.allLasers)
                ChangePowerObis(app.allLasers(ii).serialPort,value,app.allLasers(ii).maxPower);
            end
        end

        % Value changed function: galvo_XSlider
        function galvo_XSliderValueChanged(app, event)
            value = app.galvo_XSlider.Value;

            if app.current_path == 1
                app.galvo_X1voltage = value;
                app.state_path.Galvo_X(1) = value;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
            elseif app.current_path == 2
                app.galvo_X2voltage = value;
                app.state_path.Galvo_X(2) = value;
                outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
            end
            app.UITable_path.Data = app.state_path;
        end

        % Value changed function: galvo_YSlider
        function galvo_YSliderValueChanged(app, event)
            value = app.galvo_YSlider.Value;

            if app.current_path == 1
                app.galvo_Y1voltage = value;
                app.state_path.Galvo_Y(1) = value;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
            elseif app.current_path == 2
                app.galvo_Y2voltage = value;
                app.state_path.Galvo_Y(2) = value;
                outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
            end
            app.UITable_path.Data = app.state_path;
        end

        % Button pushed function: Reset_galvo_XYButton
        function Reset_galvo_XYButtonPushed(app, event)
            app.galvo_XSlider.Value = 0;
            app.galvo_YSlider.Value = 0;
            if app.current_path == 1
                app.galvo_X1voltage=0;app.state_path.Galvo_X(1) = 0;
                app.galvo_Y1voltage=0;app.state_path.Galvo_Y(1) = 0;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
            elseif app.current_path == 2
                app.galvo_X2voltage=0;app.state_path.Galvo_X(2) = 0;
                app.galvo_Y2voltage=0;app.state_path.Galvo_Y(2) = 0;
                outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.galvo_X2voltage,app.galvo_Y2voltage,app.galvo_Z2voltage]);
            end
            app.UITable_path.Data = app.state_path;
            
        end

        % Button pushed function: EnableCam
        function EnableCamButtonPushed(app, event)
            
            % open the shutter01
            if app.current_path == 1
                app.Shutterstate1 = 4;app.Shutterstate2=0;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
            elseif app.current_path == 2
                app.Shutterstate1 = 0;app.Shutterstate2=4;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
            end
            fwrite(app.controlParameters.afg, ':source2:function DC');
            fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.afg2_offset),'mv']);
            fwrite(app.controlParameters.afg, ':output2 on;');
%             fwrite(app.controlParameters.afg, ':source1:function DC');
%             fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str(app.AFG_voltage),'mv']);
%             fwrite(app.controlParameters.afg, ':output1 on;');
            
            app.f = figure;
            app.f.Position = [100 100 1024 1024];
            set(app.f,'toolbar','figure');
            hAxes = subplot(1,1,1);
            %hImage = imshow(image,'Parent',hAxes, 'DisplayRange', []); 


            app.controlParameters.MMC.stopSequenceAcquisition;
            %Make sure the buffer is empty
            app.controlParameters.MMC.clearCircularBuffer;
            %Debug
            app.controlParameters.MMC.setCircularBufferMemoryFootprint(1024*10);
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',10);
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            checkBuffer = app.controlParameters.MMC.getRemainingImageCount();
            
            if checkBuffer==0
                pause(0.5);
            end
            
            image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
            width = app.controlParameters.MMC.getImageWidth();
            height = app.controlParameters.MMC.getImageHeight();
            pixelType = 'uint16';
            image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
            image = reshape(image, [width, height]); % image should be interpreted as a 2D array
            image = rot90(image);
            hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
            %colormapeditor;
            Value = 1/(str2num(app.controlParameters.MMC.getProperty('HamamatsuHam_DCAM', 'Exposure'))/1000);
            app.myhandles = guihandles(app.f);
            %app.myhandles.shutters = app.controlParameters.shutters;
            app.myhandles.continue = true;
            %app.myhandles.textbox_handle = textbox_handle;
            while (app.myhandles.continue)
                guidata(app.f,app.myhandles);
                if app.imagecontrast_change == true
                    app.imagecontrast_change = false;
                    hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
                end
                
                checkBuffer = app.controlParameters.MMC.getRemainingImageCount();
                if checkBuffer==0
                    pause(0.6);
                end
                startTime = tic;
                image = app.controlParameters.MMC.getLastImage();
                image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                image = rot90(image);
                hImage = imshow(image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
                set(hImage,'CData',image);
                current_color = app.Lightwavelight.Value;
                                
                if (app.saveImage)
                    app.saveImage = false;
                    nameOfFile = [app.dirname,'/',num2str(app.state_stage.X),'_',...
                    num2str(app.state_stage.Y),'_',num2str(app.state_stage.Z),'_',app.LIghtpathDropDown.Value,'_',current_color,'_',date,'.tiff'];
                    app.counter = app.counter + 1;
                    imwrite(image, nameOfFile);    
                    guidata(app.f,app.myhandles);
                end
                if (app.saveImage_twopath)
                    app.saveImage_twopath = false;
                    
                    if convertCharsToStrings(current_color) == '488nm'
                        offset_GalvoX_path1 = app.state_offset{1,2};offset_GalvoY_path1 = app.state_offset{1,3};offset_GalvoZ_path1 = app.state_offset{1,4};
                        offset_GalvoX_path2 = app.state_offset{4,2};offset_GalvoY_path2 = app.state_offset{4,3};offset_GalvoZ_path2 = app.state_offset{4,4};
                    elseif convertCharsToStrings(current_color) == '561nm'
                        offset_GalvoX_path1 = app.state_offset{2,2};offset_GalvoY_path1 = app.state_offset{2,3};offset_GalvoZ_path1 = app.state_offset{2,4};
                        offset_GalvoX_path2 = app.state_offset{5,2};offset_GalvoY_path2 = app.state_offset{5,3};offset_GalvoZ_path2 = app.state_offset{5,4};
                    elseif convertCharsToStrings(current_color) == '640nm'
                        offset_GalvoX_path1 = app.state_offset{3,2};offset_GalvoY_path1 = app.state_offset{3,3};offset_GalvoZ_path1 = app.state_offset{3,4};
                        offset_GalvoX_path2 = app.state_offset{6,2};offset_GalvoY_path2 = app.state_offset{6,3};offset_GalvoZ_path2 = app.state_offset{6,4};
                    elseif convertCharsToStrings(current_color) == 'None'
                        error('no color detected.')
                    end


                    currentpath = app.current_path;
                    % for path1
                    app.Shutterstate1 = 4;app.Shutterstate2 = 0;
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1)+offset_GalvoX_path1,app.state_path.Galvo_Y(1)+offset_GalvoY_path1,app.state_path.Galvo_Z(1)+offset_GalvoZ_path1]);
                    outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2)+offset_GalvoX_path2,app.state_path.Galvo_Y(2)+offset_GalvoY_path2,app.state_path.Galvo_Z(2)+offset_GalvoZ_path2]);
                    nameOfFile = [app.dirname,'/',num2str(app.state_stage.X),'_',...
                    num2str(app.state_stage.Y),'_',num2str(app.state_stage.Z),'_path1_',current_color,'_',date,'.tiff'];
                    pause(0.5);
                    image = app.controlParameters.MMC.getLastImage();
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    image = rot90(image);
                    imwrite(image, nameOfFile);
                    
                    % for path2
                    app.Shutterstate1 = 0;app.Shutterstate2 = 4;
                    outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1)+offset_GalvoX_path1,app.state_path.Galvo_Y(1)+offset_GalvoY_path1,app.state_path.Galvo_Z(1)+offset_GalvoZ_path1]);
                    outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2)+offset_GalvoX_path2,app.state_path.Galvo_Y(2)+offset_GalvoY_path2,app.state_path.Galvo_Z(2)+offset_GalvoZ_path2]);
                    nameOfFile = [app.dirname,'/',num2str(app.state_stage.X),'_',...
                    num2str(app.state_stage.Y),'_',num2str(app.state_stage.Z),'_path2_',current_color,'_',date,'.tiff'];
                    pause(0.5);
                    image = app.controlParameters.MMC.getLastImage();
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    image = rot90(image);
                    imwrite(image, nameOfFile);
                    
                    app.current_path = currentpath;
                    if app.current_path == 1
                        
                        app.Shutterstate1 = 4;app.Shutterstate2 = 0;
                        outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                        outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
                        %disp(1)
                    elseif app.current_path == 2
                        
                        app.Shutterstate1 = 0;app.Shutterstate2 = 4;
                        outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                        outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
                %disp(2)
                    
                    end
                end
                elTime = toc(startTime);
                if (elTime <= (app.expousreTime/1000))
                    pause(app.expousreTime/1000 - elTime);                
                else
                    pause(0.15);
                end
                %app.myhandles = guidata(app.f);
            end
            
        end

        % Button pushed function: DisableCam
        function DisableCamButtonPushed(app, event)
            app.myhandles.continue = false; 
            close(app.f);
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            % close the shutter
            app.Shutterstate1 = 0;
            app.Shutterstate2 = 0;
            outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
            outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
            
        end

        % Value changed function: ExtStepsizeDropDown
        function ExtStepsizeDropDownValueChanged(app, event)
            value = app.ExtStepsizeDropDown.Value;
            
            if str2num(value) == 0.1
                app.stepSizeExt = 0.1;
            end
            if str2num(value) == 0.01
                app.stepSizeExt = 0.01;
            end
            if str2num(value) == 0.001
                app.stepSizeExt = 0.001;
            end
        end

        % Button pushed function: leftButtonExt
        function leftButtonExtPushed(app, event)
            if app.current_path == 1
                fprintf(app.controlParameters.sExtLens1,['1PR',num2str(app.stepSizeExt)]);
                pause(1)
                fprintf(app.controlParameters.sExtLens1,'1TP?');
                pos = fscanf(app.controlParameters.sExtLens1);
            else
                fprintf(app.controlParameters.sExtLens2,['1PR',num2str(app.stepSizeExt)]);
                pause(1)
                fprintf(app.controlParameters.sExtLens2,'1TP?');
                pos = fscanf(app.controlParameters.sExtLens2);
            end
            pos = getpos(pos);
            %app.ExtPos.Value = str2num(pos);
            if app.current_path == 1
                app.state_motor.MotorPosition_mm_(1) = str2num(pos);
            else
                app.state_motor.MotorPosition_mm_(2) = str2num(pos);
            end
            app.UITable_motor.Data = app.state_motor;
        end

        % Button pushed function: rightButtonExt
        function rightButtonExtPushed(app, event)
            if app.current_path == 1
                fprintf(app.controlParameters.sExtLens1,['1PR-',num2str(app.stepSizeExt)]);
                pause(1)
                fprintf(app.controlParameters.sExtLens1,'1TP?');
                pos = fscanf(app.controlParameters.sExtLens1);
            else
                fprintf(app.controlParameters.sExtLens2,['1PR-',num2str(app.stepSizeExt)]);
                pause(1)
                fprintf(app.controlParameters.sExtLens2,'1TP?');
                pos = fscanf(app.controlParameters.sExtLens2);
            end
            pos = getpos(pos);
            %app.ExtPos.Value = str2num(pos);
            if app.current_path == 1
                app.state_motor.MotorPosition_mm_(1) = str2num(pos);
            else
                app.state_motor.MotorPosition_mm_(2) = str2num(pos);
            end
            app.UITable_motor.Data = app.state_motor;
        end

        % Value changed function: Imagecontrast
        function ImagecontrastValueChanged(app, event)
            value = app.Imagecontrast.Value;
            app.imagecontrast_change = true;
            if value < 0 
                app.Imagecontrast.Value = 300;
            elseif value >= 65535
                app.Imagecontrast.Value = 65500;
            end
        end

        % Value changed function: Filterwheel
        function FilterwheelValueChanged(app, event)
            value = app.Filterwheel.Value;
            if convertCharsToStrings(value) == 'None'
                fprintf(app.controlParameters.sFWDet, 'pos=1');
            elseif convertCharsToStrings(value) == 'Blue'
                fprintf(app.controlParameters.sFWDet, 'pos=2');
            elseif convertCharsToStrings(value) == 'Green'
                fprintf(app.controlParameters.sFWDet, 'pos=3');
            elseif convertCharsToStrings(value) == 'Red'
                fprintf(app.controlParameters.sFWDet, 'pos=4');
            end
        end

        % Button pushed function: SaveImage
        function SaveImageButtonPushed(app, event)
            if app.savepathEditField.Value == ""
                    disp('Please set the saving directory.')
                    return;
            end
            app.saveImage = true;
        end

        % Value changed function: Updown_stepDropDown
        function Updown_stepDropDownValueChanged(app, event)
            value = app.Updown_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.Stage_step_X = 10;
            elseif convertCharsToStrings(value) == '50um'
                app.Stage_step_X = 50;
            elseif convertCharsToStrings(value) == '100um'
                app.Stage_step_X = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.Stage_step_X = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.Stage_step_X = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.Stage_step_X = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.Stage_step_X = 2000;
            elseif convertCharsToStrings(value) == '1cm'
                app.Stage_step_X = 10000;
            end
        end

        % Value changed function: Inout_stepDropDown
        function Inout_stepDropDownValueChanged(app, event)
            value = app.Inout_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.Stage_step_Y = 10;
            elseif convertCharsToStrings(value) == '50um'
                app.Stage_step_Y = 50;
            elseif convertCharsToStrings(value) == '100um'
                app.Stage_step_Y = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.Stage_step_Y = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.Stage_step_Y = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.Stage_step_Y = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.Stage_step_Y = 2000;
            elseif convertCharsToStrings(value) == '1cm'
                app.Stage_step_Y = 10000;
            end
        end

        % Value changed function: Leftright_stepDropDown
        function Leftright_stepDropDownValueChanged(app, event)
            value = app.Leftright_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.Stage_step_Z = 10;
            elseif convertCharsToStrings(value) == '50um'
                app.Stage_step_Z = 50;
            elseif convertCharsToStrings(value) == '100um'
                app.Stage_step_Z = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.Stage_step_Z = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.Stage_step_Z = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.Stage_step_Z = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.Stage_step_Z = 2000;
            elseif convertCharsToStrings(value) == '1cm'
                app.Stage_step_Z = 10000;
            end
        end

        % Value changed function: Rotation_stepDropDown
        function Rotation_stepDropDownValueChanged(app, event)
            value = app.Rotation_stepDropDown.Value;
            if convertCharsToStrings(value) == '10um'
                app.Stage_step_T = 10;
            elseif convertCharsToStrings(value) == '50um'
                app.Stage_step_T = 50;
            elseif convertCharsToStrings(value) == '100um'
                app.Stage_step_T = 100;
            elseif convertCharsToStrings(value) == '200um'
                app.Stage_step_T = 200;
            elseif convertCharsToStrings(value) == '500um'
                app.Stage_step_T = 500;
            elseif convertCharsToStrings(value) == '1mm'
                app.Stage_step_T = 1000;
            elseif convertCharsToStrings(value) == '2mm'
                app.Stage_step_T = 4000;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            current_x = app.controlParameters.MMC.getXPosition();
            if current_x - app.Stage_step_X < -19500
                f=msgbox('Reach the limit.','warning');
                return;
            end
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',-app.Stage_step_X,0);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            
%             app.X_pos.Value = app.controlParameters.MMC.getXPosition();
            app.state_stage.X = app.controlParameters.MMC.getXPosition();
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            current_x = app.controlParameters.MMC.getXPosition();
            if current_x+ app.Stage_step_X> 6200
                f=msgbox('Reach the limit.','warning');
                return;
            end
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',app.Stage_step_X,0);
            
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
%             app.X_pos.Value = app.controlParameters.MMC.getXPosition();
            app.state_stage.X = app.controlParameters.MMC.getXPosition();
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
        
            current_y = app.controlParameters.MMC.getYPosition();
            if current_y - app.Stage_step_Y < 2500
                f=msgbox('Reach the limit.','warning');
                return;
            end
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,-app.Stage_step_Y);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            %app.Y_pos.Value = app.controlParameters.MMC.getYPosition();
            app.state_stage.Y = app.controlParameters.MMC.getYPosition();
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
        
            current_y = app.controlParameters.MMC.getYPosition();
            if current_y + app.Stage_step_Y > 25000
                f=msgbox('Reach the limit.','warning');
                return;
            end
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,app.Stage_step_Y);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            %app.Y_pos.Value = app.controlParameters.MMC.getYPosition();
            app.state_stage.Y = app.controlParameters.MMC.getYPosition();
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            
            current_z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            if current_z + app.Stage_step_Z > 7000
                f=msgbox('Reach the limit.','warning');
                return;
            end
            app.controlParameters.MMC.setRelativePosition('ZStage:Z:32',app.Stage_step_Z);
            app.controlParameters.MMC.waitForDevice('ZStage:Z:32');
            %app.Z_pos.Value = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.state_stage.Z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            
            current_z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            if current_z -app.Stage_step_Z < -21500
                f=msgbox('Reach the limit.','warning');
                return;
            end
            app.controlParameters.MMC.setRelativePosition('ZStage:Z:32',-app.Stage_step_Z);
            app.controlParameters.MMC.waitForDevice('ZStage:Z:32');
            %app.Z_pos.Value = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.state_stage.Z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            app.controlParameters.MMC.setRelativePosition('ZStage:T:32',-app.Stage_step_T);
            app.controlParameters.MMC.waitForDevice('ZStage:T:32');
            %app.T_pos.Value = app.controlParameters.MMC.getPosition('ZStage:T:32');
            app.state_stage.T = app.controlParameters.MMC.getPosition('ZStage:T:32');
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            app.controlParameters.MMC.setRelativePosition('ZStage:T:32',app.Stage_step_T);
            app.controlParameters.MMC.waitForDevice('ZStage:T:32');
            %app.T_pos.Value = app.controlParameters.MMC.getPosition('ZStage:T:32');
            app.state_stage.T = app.controlParameters.MMC.getPosition('ZStage:T:32');
            app.UITable_stage.Data = app.state_stage;
        end

        % Button pushed function: MoveandStitch_2DButton
        function MoveandStitch_2DButtonPushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            if app.savepathEditField.Value == ""
                disp('Please set the saving directory.')
                return;
            end
            pixelSizeUm = 6.5;
            tubeLensFocalDistance = 180; %mm
            objectiveDesignedTubeLensFocal = 180; %mm
            objectiveMag = 10;overlap = 0;
            downSampleFactor = 1;
            effectivePixelSize = (pixelSizeUm/objectiveMag)*(objectiveDesignedTubeLensFocal/tubeLensFocalDistance)*downSampleFactor;
            stepSizeInLateralScan = effectivePixelSize*(2048/downSampleFactor)*(100-overlap)/100;
            if app.current_path == 1
                app.Shutterstate1=4;app.Shutterstate2=0;
                fwrite(app.controlParameters.afg, ':output1 on;');pause(.5);
                outputSingleScan(app.controlParameters.s1,[4,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                fwrite(app.controlParameters.afg, ':output2 off;');pause(.5);
                outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
            elseif app.current_path == 2
                app.Shutterstate1=0;app.Shutterstate2=4;
                fwrite(app.controlParameters.afg, ':output1 off;');pause(.5);
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                fwrite(app.controlParameters.afg, ':output2 on;');pause(.5);
                outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
            end
                downImage = 0.1;
            [init_xPos,init_yPos,init_zPos]=getXYZposition(app.controlParameters.MMC);
            app.waitbar = uifigure;
            app.wait_status = uiprogressdlg(app.waitbar,'Title','Take images','Message','Begining','Cancelable','on');
            %pause(0.2);
            [AllImage,xyzScanArray]=MoveandStitch(app,app.Vertiles_num.Value,app.Horztiles_num.Value,stepSizeInLateralScan);
            %save Allimage AllImage
            close(app.waitbar);
            close(app.wait_status);
            AllImage = uint16(AllImage);
            time = datevec(datestr(now));
            name_of_file = [app.dirname,'/',num2str(time(1:5)),'_',app.Lightwavelight.Value,'.tiff'];
            imwrite(AllImage,name_of_file);
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            dx = init_xPos-xPos;dy=init_yPos-yPos;dz=init_zPos-zPos;
            SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
            app.Shutterstate1 = 0;app.Shutterstate2=0;
            outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
            outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
            %fwrite(app.controlParameters.afg, ':output1 off;');
        end

        % Callback function
        function MoveandStitch_3DButtonPushed(app, event)
            pixelSizeUm = 6.5;
            tubeLensFocalDistance = 180; %mm
            objectiveDesignedTubeLensFocal = 180; %mm
            objectiveMag = 10;overlap = -95;
            downSampleFactor = 1;
            effectivePixelSize = (pixelSizeUm/objectiveMag)*(objectiveDesignedTubeLensFocal/tubeLensFocalDistance)*downSampleFactor;
            stepSizeInLateralScan = effectivePixelSize*(2048/downSampleFactor)*(100-overlap)/100;
            fwrite(app.controlParameters.afg, ':output1 on;');pause(.5);outputSingleScan(app.controlParameters.s,[4,0,0,0]);
            z_stack_step = 10;
            
            for i = 1:app.Z_stack.Value
                app.controlParameters.MMC.setRelativeXYPosition(0,z_stack_step);
                [AllImage,xyzScanArray]=MoveandStitch(app,app.Vertiles_num.Value,app.Horztiles_num.Value,stepSizeInLateralScan);
                AllImage = uint16(AllImage);
                name_of_file = ['../zstacks/cochlea/_ChenLi_cochlea_',date,num2str(i,'%04d'),'.tiff'];
                imwrite(AllImage,name_of_file);
            end
        end

        % Value changed function: ObjStepsizeDropDown
        function ObjStepsizeDropDownValueChanged(app, event)
            value = app.ObjStepsizeDropDown.Value;
            
            if str2num(value) == 0.01
                app.stepSizeObj = 0.01;
            end
            if str2num(value) == 0.005
                app.stepSizeObj = 0.005;
            end
            if str2num(value) == 0.001
                app.stepSizeObj = 0.001;
            end
        end

        % Button pushed function: leftButtonObj
        function leftButtonObjPushed(app, event)
            fprintf(app.controlParameters.sDetLens,['1PR',num2str(app.stepSizeObj)]);
            pause(0.5) 
            fprintf(app.controlParameters.sDetLens,'1TP?');
            pos = fscanf(app.controlParameters.sDetLens);
            pos = getpos(pos);
            %app.ObjPos.Value = str2num(pos);
            app.state_motor.MotorPosition_mm_(3) = str2num(pos); 
            app.UITable_motor.Data = app.state_motor;
        end

        % Button pushed function: rightButtonObj
        function rightButtonObjPushed(app, event)
            fprintf(app.controlParameters.sDetLens,['1PR-',num2str(app.stepSizeObj)]);
            pause(0.5)
            fprintf(app.controlParameters.sDetLens,'1TP?');
            pos = fscanf(app.controlParameters.sDetLens);
            pos = getpos(pos);
            %app.ObjPos.Value = str2num(pos);
            app.state_motor.MotorPosition_mm_(3) = str2num(pos); 
            app.UITable_motor.Data = app.state_motor;
        end

        % Callback function
        function movetileButtonPushed(app, event)
            if app.start_3D==false
                app.scanindex = 1;
                app.start_3D = true;
                disp(['This is 1 tile.'])
            elseif app.scanindex <= app.scancol
                disp(['This is ',num2str(app.scanindex),' tile.'])
            elseif app.scanindex > app.scancol
                disp('all tiles are finished');
                a = app.allcheckpoints;
                save('allpoints.mat','a')
                return;
            end
            
            [xPos, yPos, zPos] = getXYZposition(app.controlParameters.MMC);
            dx = app.xyzScanArray(1,app.scanindex) - xPos; dy = app.xyzScanArray(2,app.scanindex) - yPos; dz = app.xyzScanArray(3,app.scanindex) - zPos;
            SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
            app.scanindex = app.scanindex + 1;
        end

        % Callback function
        function save_checkpointButtonPushed(app, event)
            if app.start_correct==false
                app.start_correct = true;
                app.checkpoints_index=1;
                [app.xinitPos,app.yinitPos,app.zinitPos] = getXYZposition(app.controlParameters.MMC);
                app.xinitGalvo = app.galvo_XSlider.Value;
                app.yinitGalvo = app.galvo_YSlider.Value;
                app.zinitGalvo = app.galvo_ZSlider.Value;
                start_z_position = app.controlParameters.MMC.getYPosition();
                app.checkpoints_valuelist = zeros(app.Check_points.Value,5);
                app.zstacksposs = zeros(app.num_zstacks.Value,1);
                for i =1:app.num_zstacks.Value
                    app.zstacksposs(i,:) = start_z_position + (i-1)*app.zstack_distanceum.Value;
                end
                app.checkpoints_valuelist(:,1) = floor(linspace(app.zstacksposs(1),app.zstacksposs(end),app.Check_points.Value));
                pos_obj = posfind(app.controlParameters.sDetLens);            
                app.checkpoints_valuelist(app.checkpoints_index,2) = str2num(pos_obj);
                app.checkpoints_valuelist(app.checkpoints_index,3) = app.galvo_XSlider.Value;
                app.checkpoints_valuelist(app.checkpoints_index,4) = app.galvo_YSlider.Value;
                app.checkpoints_valuelist(app.checkpoints_index,5) = app.galvo_ZSlider.Value;
                app.checkpoints_index = app.checkpoints_index + 1;
            end
            if app.checkpoints_index > app.Check_points.Value
                app.allcheckpoints{app.scanindex-1} = app.checkpoints_valuelist;
                [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                dy = app.yinitPos - yPos;
                dx = app.xinitPos - xPos;
                dz = app.zinitPos - zPos;                
                SetRelativePos(app.controlParameters.MMC,0,dy,0);  
                disp('move to the next tile');
                app.start_correct = false;
                app.galvo_XSlider.Value = app.xinitGalvo;app.galvo_Xview.Value = app.xinitGalvo;app.galvo_Xvoltage=app.xinitGalvo;
                app.galvo_YSlider.Value = app.yinitGalvo;app.galvo_Yview.Value = app.yinitGalvo;app.galvo_Yvoltage=app.yinitGalvo;
                app.galvo_ZSlider.Value = app.zinitGalvo;app.galvo_Zview.Value = app.zinitGalvo;app.galvo_Zvoltage=app.zinitGalvo;
                outputSingleScan(app.controlParameters.s,[app.Shutterstate01,app.galvo_Xvoltage,app.galvo_Yvoltage,app.galvo_Zvoltage]);
                return;
            end
            pos_obj = posfind(app.controlParameters.sDetLens);            
            app.checkpoints_valuelist(app.checkpoints_index,2) = str2num(pos_obj);
            app.checkpoints_valuelist(app.checkpoints_index,3) = app.galvo_XSlider.Value;
            app.checkpoints_valuelist(app.checkpoints_index,4) = app.galvo_YSlider.Value;
            app.checkpoints_valuelist(app.checkpoints_index,5) = app.galvo_ZSlider.Value;
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            dy = app.checkpoints_valuelist(app.checkpoints_index,1) - yPos;
            SetRelativePos(app.controlParameters.MMC,0,dy,0);
            app.checkpoints_index = app.checkpoints_index + 1;
        end

        % Value changed function: LIghtpathDropDown
        function LIghtpathDropDownValueChanged(app, event)
            value = app.LIghtpathDropDown.Value;
            if value == 'path1'
                app.current_path = 1;
                app.Shutterstate1 = 4;app.Shutterstate2 = 0;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
                %disp(1)
            elseif value == 'path2'
                app.current_path = 2;
                app.Shutterstate1 = 0;app.Shutterstate2 = 4;
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
                outputSingleScan(app.controlParameters.s2,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
                %disp(2)
            end
        end

        % Cell edit callback: UITable_afg
        function UITable_afgCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            %app.state_afg.Middle_mv_(1)
            %disp(indices);disp(newData);
%             if indices(1) == 1
%                 if indices(2) == 2
%                     app.state_afg.Middle_mv_(1) = newData;
%                     fwrite(app.controlParameters.afg, ':source1:function DC');
%                     fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str(newData),'mv']);
%                     fwrite(app.controlParameters.afg, ':output1 on;');
%                 elseif indices(2) == 3
%                     app.state_afg.Top_mv_(1) = newData;
%                 elseif indices(2) == 4
%                     app.state_afg.Bottom_mv_(1) = newData;
%                 end
%             end
%             if indices(1) == 2
%                 if indices(2) == 2
%                     app.state_afg.Middle_mv_(2) = newData;
%                     fwrite(app.controlParameters.afg, ':source1:function DC');
%                     fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(newData),'mv']);
%                     fwrite(app.controlParameters.afg, ':output2 on;');
%                 elseif indices(2) == 3
%                     app.state_afg.Top_mv_(2) = newData;
%                 elseif indices(2) == 4
%                     app.state_afg.Bottom_mv_(2) = newData;
%                 end
%             end
            
            app.state_afg{indices(1),indices(2)} = newData;
            app.state_afg;
        end

        % Value changed function: AFG_scanButton
        function AFG_scanButtonValueChanged(app, event)
            value = app.AFG_scanButton.Value;
            if any([app.state_afg.Top_mv_]>2000)
                disp('voltage is too high.');
                return;
            elseif any([app.state_afg.Bottom_mv_]<-2000)
                disp('voltage is too low.');
                return;
            elseif any([app.state_afg.Bottom_mv_]>[app.state_afg.Top_mv_])
                disp('Bottom is larger than top.');
                return;
            end
            
            index=0;
            if convertCharsToStrings(app.Lightwavelight.Value) == 'None'
                f=msgbox('Choose a wavelength.','warning');
                return;
            elseif convertCharsToStrings(app.Lightwavelight.Value) == '488nm'
                index = 1;
            elseif convertCharsToStrings(app.Lightwavelight.Value) == '561nm'
                index = 2;
            elseif convertCharsToStrings(app.Lightwavelight.Value) == '640nm'
                index = 3;
            end
            if value == 1
                
                if app.current_path == 1
                    
                    SetAfgRamp(app.controlParameters.afg,app.state_afg.Bottom_mv_(index),app.state_afg.Top_mv_(index),600,50,1);
                    fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str(app.state_afg.Offset_mv_(index)),'mv']);
                    fwrite(app.controlParameters.afg, ':output1 on;');                    
                else
                    SetAfgRamp(app.controlParameters.afg,app.state_afg.Bottom_mv_(index+3),app.state_afg.Top_mv_(index+3),600,50,2);
                    fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.state_afg.Offset_mv_(index+3)),'mv']);
                    fwrite(app.controlParameters.afg, ':output2 on;');
                end
                
            else
                if app.current_path == 1
                    %fwrite(app.controlParameters.afg, ':output1 off;'); 
                    fwrite(app.controlParameters.afg, ':source1:function DC');
                    fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str(app.state_afg.Offset_mv_(index)),'mv']);
                    fwrite(app.controlParameters.afg, ':output1 on;');
                else
                    %fwrite(app.controlParameters.afg, ':output2 off;'); 
                    fwrite(app.controlParameters.afg, ':source2:function DC');
                    fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.state_afg.Offset_mv_(index+3)),'mv']);
                    fwrite(app.controlParameters.afg, ':output2 on;');
                end
                
            end
        end

        % Cell edit callback: UITable_motor
        function UITable_motorCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            if abs(newData)>10 || abs(newData)<2
                error('new data is too high or too low')
            end
            if indices(1) == 1
                
                fprintf(app.controlParameters.sExtLens1,['1PA',num2str(newData)]);
            elseif indices(1) == 2
                fprintf(app.controlParameters.sExtLens2,['1PA',num2str(newData)]);
            end
        end

        % Cell edit callback: UITable_path
        function UITable_pathCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if abs(newData)>2
                error('the vlotage is too high')
            end
            if indices(1) == 1
                if indices(2) == 2
                    app.state_path.Galvo_X(1) = newData;
                    app.galvo_XSlider.Value = newData;
                elseif indices(2) == 3
                    app.state_path.Galvo_Y(1) = newData;
                    app.galvo_YSlider.Value = newData;
                elseif indices(2) == 4
                    app.state_path.Galvo_Z(1) = newData;
                    app.galvo_ZSlider.Value = newData;
                end
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate1,app.state_path.Galvo_X(1),app.state_path.Galvo_Y(1),app.state_path.Galvo_Z(1)]);
            end
            if indices(1) == 2
                if indices(2) == 2
                    app.state_path.Galvo_X(2) = newData;
                    app.galvo_XSlider.Value = newData;
                elseif indices(2) == 3
                    app.state_path.Galvo_Y(2) = newData;
                    app.galvo_YSlider.Value = newData;
                elseif indices(2) == 4
                    app.state_path.Galvo_Z(2) = newData;
                    app.galvo_ZSlider.Value = newData;
                end
                outputSingleScan(app.controlParameters.s1,[app.Shutterstate2,app.state_path.Galvo_X(2),app.state_path.Galvo_Y(2),app.state_path.Galvo_Z(2)]);
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(gcbf);            
        end

        % Button pushed function: SaveImagetwopathButton
        function SaveImagetwopathButtonPushed(app, event)
            if app.savepathEditField.Value == ""
                disp('Please set the saving directory.')
                return;
            end
            app.saveImage_twopath = true;
        end

        % Button pushed function: ImagesavingdirectoryButton
        function ImagesavingdirectoryButtonPushed(app, event)
            path = uigetdir('Z:/','Image saving directory');
            app.dirname = path;
            app.savepathEditField.Value = path;

        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            app.Start_posEditField.Value = yPos;
        end

        % Button pushed function: saveButton_2
        function saveButton_2Pushed(app, event)
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            app.End_posEditField.Value = yPos;
            
        end

        % Button pushed function: saveCheckpointsButton
        function saveCheckpointsButtonPushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            if app.Start_posEditField.Value == 0 || app.End_posEditField.Value == 0
                fig = uifigure;
                uialert(fig,'Please save the start and end points.','warning');
                disp('Please save the start and end points.');
                return;
            end
            if abs(app.Start_posEditField.Value-app.End_posEditField.Value)<30
                fig = uifigure;
                uialert(fig,'The distance between start and end point is too small.','warning');
                disp('The distance between start and end point is too small.')
                return;
            end
            
            app.checkpoints_zpos = round(linspace(app.Start_posEditField.Value,app.End_posEditField.Value,app.Check_points.Value));
            numel(app.checkpoints_zpos);
            for i=1:numel(app.checkpoints_zpos)
                app.checkpoints_onetile(i).abc = 0; % initalize the size
            end
            if app.onetile_index == 0
%                 fig = uifigure;
%                 uialert(fig,'Please move to the first checkpoints.','warning');
                f = msgbox('Please move to the first checkpoints.','warning');
                disp('Please move to the first checkpoints.');
                return;
                [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                %yPos
                dy = app.Start_posEditField.Value - yPos;
                SetRelativePos(app.controlParameters.MMC,0,dy,0);
                [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                %yPos
            end
            % save all states
            app.checkpoints_onetile(app.onetile_index).current_path = app.current_path;
            app.checkpoints_onetile(app.onetile_index).Galvo_X = app.state_path.Galvo_X(app.current_path);
            app.checkpoints_onetile(app.onetile_index).Galvo_Y = app.state_path.Galvo_Y(app.current_path);
            app.checkpoints_onetile(app.onetile_index).Galvo_Z = app.state_path.Galvo_Z(app.current_path);
            app.checkpoints_onetile(app.onetile_index).Excitionlens = app.state_motor.MotorPosition_mm_(app.current_path);
            app.checkpoints_onetile(app.onetile_index).Detectionlens = app.state_motor.MotorPosition_mm_(3);
            app.checkpoints_onetile(app.onetile_index).wavelengh = app.Lightwavelight.Value;
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            app.checkpoints_onetile(app.onetile_index).xPos = xPos;
            app.checkpoints_onetile(app.onetile_index).yPos = yPos;
            app.checkpoints_onetile(app.onetile_index).zPos = zPos;
            app.checkpoints_onetile(app.onetile_index).saved = true;
            %app.checkpoints_onetile
            %a=app.checkpoints_onetile.current_path
            for i=1:numel(app.checkpoints_zpos)
                if isempty(app.checkpoints_onetile(i).saved)
                    save(i)=0;
                else
                    save(i)=1;
                end
            end
            app.StateofCheckPointsEditField.Value = num2str(save);
            save_num = 0;
            for i=1:numel(app.checkpoints_zpos)
                
                if ~isempty(app.checkpoints_onetile(i).current_path)
                    save_num = save_num + 1;
                end
            end
            if save_num == app.Check_points.Value       
                disp('All checkpoints on this tile is saved.');
            end
        end

        % Value changed function: movecheckpointsSpinner
        function movecheckpointsSpinnerValueChanged(app, event)
            value = app.movecheckpointsSpinner.Value;
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            if value > app.Check_points.Value
                app.movecheckpointsSpinner.Value = app.Check_points.Value;
            elseif value < 1
                app.movecheckpointsSpinner.Value = 1;
            end
            app.onetile_index = app.movecheckpointsSpinner.Value;
            a = app.onetile_index;
            
            if app.Start_posEditField.Value == 0 || app.End_posEditField.Value == 0
                f=msgbox('Please save the start and end points.','warning');
                return;
            end
            if abs(app.Start_posEditField.Value-app.End_posEditField.Value)<30
                f=msgbox('The distance between start and end point is too small.','warning');
                return;
            end
            
            app.checkpoints_zpos = round(linspace(app.Start_posEditField.Value,app.End_posEditField.Value,app.Check_points.Value));
            
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            dy = app.checkpoints_zpos(app.onetile_index) - yPos;
            SetRelativePos(app.controlParameters.MMC,0,dy,0);
            %[xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            
        end

        % Button pushed function: takeonetileimagesButton
        function takeonetileimagesButtonPushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            % check if all checkpoints are saved
            save_num = 0;
            for i=1:numel(app.checkpoints_zpos)
                
                if ~isempty(app.checkpoints_onetile(i).current_path)
                    save_num = save_num + 1;
                end
            end
            if save_num ~= app.Check_points.Value       
                disp('please save all checkpoints.')
                return;
            end    
        
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            
            if app.checkpoints_onetile(1).current_path == 1
                outputSingleScan(app.controlParameters.s1,[4,app.checkpoints_onetile(1).Galvo_X,app.checkpoints_onetile(1).Galvo_Y,app.checkpoints_onetile(1).Galvo_Z]);
                outputSingleScan(app.controlParameters.s2,[0,0,0,0]);
            else
                outputSingleScan(app.controlParameters.s2,[4,app.checkpoints_onetile(1).Galvo_X,app.checkpoints_onetile(1).Galvo_Y,app.checkpoints_onetile(1).Galvo_Z]);
                outputSingleScan(app.controlParameters.s1,[0,0,0,0]);
            end
            if app.savepathEditField.Value == ""
                disp('Please set the saving directory');
                return;
            end
            time = datevec(datestr(now));
            folder_name = num2str(time(1:5)); 
            saving_dir = [app.dirname,'/',folder_name,'/',app.Lightwavelight.Value];
            if ~exist(saving_dir)
                mkdir(saving_dir);
            end
            for i=1:numel(app.checkpoints_onetile)
                Galvo_X(i) = app.checkpoints_onetile(i).Galvo_X;
                Galvo_Y(i) = app.checkpoints_onetile(i).Galvo_Y;
                Galvo_Z(i) = app.checkpoints_onetile(i).Galvo_Z;
                Excitionlens(i) = app.checkpoints_onetile(i).Excitionlens;
                Detectionlens(i) = app.checkpoints_onetile(i).Detectionlens;
                xpos(i) = app.checkpoints_onetile(i).xPos;
                ypos(i) = app.checkpoints_onetile(i).yPos;
                zpos(i) = app.checkpoints_onetile(i).zPos;
                
            end
            % begin to take images
            positions = round(app.Start_posEditField.Value:app.zstack_distanceum.Value:app.End_posEditField.Value);
            GalvoX_pos = interp1(ypos,Galvo_X,positions,'linear','extrap');
            GalvoY_pos = interp1(ypos,Galvo_Y,positions,'linear','extrap');
            GalvoZ_pos = interp1(ypos,Galvo_Z,positions,'linear','extrap');
            Excitionlens_pos = interp1(ypos,Excitionlens,positions,'linear','extrap');
            Detectionlens_pos = interp1(ypos,Detectionlens,positions,'linear','extrap');
            
            % start camera
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            checkBuffer = app.controlParameters.MMC.getRemainingImageCount();
            if checkBuffer==0
                pause(0.7);
            end
            % taking images on one tile  
            % calculate the interval value
            % generate waitbar
            
            app.waitbar = uifigure;
            app.wait_status = uiprogressdlg(app.waitbar,'Title','Take images','Message','Begining','Cancelable','on');
            
            for i=1:numel(positions)
%                 checkBuffer = app.controlParameters.MMC.getRemainingImageCount();
%                 if checkBuffer==0
%                     pause(0.9);
%                 end
                % update waitbar
                app.wait_status.Value = i/numel(positions);
                app.wait_status.Message = ['Images ',num2str(i),'/',num2str(numel(positions))];
                
                [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                axis1 = num2str(round(xPos)*10);
                axis2 = num2str(round(zPos)*10);
                axis3 = num2str(round(yPos)*10);
                SetRelativePos(app.controlParameters.MMC,0,positions(i)-yPos,0);
                % change the state according to interp
                if app.checkpoints_onetile(1).current_path == 1
                    outputSingleScan(app.controlParameters.s1,[4,GalvoX_pos(i),GalvoY_pos(i),GalvoZ_pos(i)])
                    fprintf(app.controlParameters.sExtLens1,['1PA',num2str(Excitionlens_pos(i))]);
                elseif app.checkpoints_onetile(1).current_path == 2
                    outputSingleScan(app.controlParameters.s2,[4,GalvoX_pos(i),GalvoY_pos(i),GalvoZ_pos(i)])
                    fprintf(app.controlParameters.sExtLens2,['1PA',num2str(Excitionlens_pos(i))]);
                end
                fprintf(app.controlParameters.sDetLens,['1PA',num2str(Detectionlens_pos(i))]);
                % save images
                while (app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                    if app.controlParameters.MMC.getRemainingImageCount()>0
                        image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                        width = app.controlParameters.MMC.getImageWidth();
                        height = app.controlParameters.MMC.getImageHeight();
                        pixelType = 'uint16';
                        image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                        image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                        Image = rot90(image);
                        image_path = [saving_dir,'/',axis1,'_',axis2,'_',axis3,'.tiff'];
                        imwrite(Image,image_path);
                        break;
                    end
                end                
                if app.wait_status.CancelRequested
                    break;
                end
            end
            close(app.waitbar);
            close(app.wait_status);
            % set to original pos
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            SetRelativePos(app.controlParameters.MMC,0,positions(1)-yPos,0);
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
        end

        % Button pushed function: saveButton_3
        function saveButton_3Pushed(app, event)
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            app.startofeachtileEditField.Value = yPos;
        end

        % Button pushed function: saveButton_4
        function saveButton_4Pushed(app, event)
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            if yPos <= (app.startofeachtileEditField.Value + 5)
                f=msgbox('the coordinate of end pos should be larger than start.','warning');
                return;
            end
            app.endofeachtileEditField.Value = yPos;
        end

        % Button pushed function: createscanpatterncenterButton
        function createscanpatterncenterButtonPushed(app, event)
            if app.VerticaltileEditField.Value>=15 || app.HorizontaltileEditField.Value>=15 || app.CheckPointsEditField.Value<=-1
                f=msgbox('check if the tile number is too larger or checkpoints is too small.','warning');
                return;
            end
            
            text = {['Take multitile images? ',num2str(app.VerticaltileEditField.Value),' by ',num2str(app.HorizontaltileEditField.Value)]};
            answer = questdlg(text,'light sheet microscope','Yes','No','Cancel','Cancel');
            if strcmp(answer,'Yes')
                
                app.StatusLamp.Color = [0 1 0];
                app.xyzScanArray_status = true;
                disp('new scan');
                
            elseif strcmp(answer,'No')
                disp('old');
                app.StatusLamp.Color = [1 0 0];
                app.xyzScanArray_status = false;
                
                return;
            elseif strcmp(answer,'Cancel')
                disp('Cancel.');
                return;
            end


            pixelSizeUm = 6.5;
            tubeLensFocalDistance = 180; %mm
            objectiveDesignedTubeLensFocal = 180; %mm
            objectiveMag = 10;overlap = 15;
            downSampleFactor = 1;
            effectivePixelSize = (pixelSizeUm/objectiveMag)*(objectiveDesignedTubeLensFocal/tubeLensFocalDistance)*downSampleFactor;
            stepSizeInLateralScan = effectivePixelSize*(2048/downSampleFactor)*(100-overlap)/100;
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            app.xinitPos=xPos;
            app.yinitPos=yPos;
            app.zinitPos=zPos;
            [app.xyzScanArray]=createScanpattern(xPos,yPos,zPos,app.VerticaltileEditField.Value,app.HorizontaltileEditField.Value,stepSizeInLateralScan);           
            a=app.xyzScanArray;
            [m,tile_num]=size(app.xyzScanArray);
            % initialize all checkpoints
            for i=1:tile_num
                for j=1:app.CheckPointsEditField.Value
                    app.checkpoints_multipletile(i,j).saved_488nm=false;
                    app.checkpoints_multipletile(i,j).saved_561nm=false;
                    app.checkpoints_multipletile(i,j).saved_640nm=false;
                    app.checkpoints_multipletile(i,j).tile_xpos=app.xyzScanArray(1,i);
                    app.checkpoints_multipletile(i,j).tile_ypos=app.xyzScanArray(2,i);
                    app.checkpoints_multipletile(i,j).tile_zpos=app.xyzScanArray(3,i);
                end
            end
            %app.xyzScanArray_status = true;
            
        end

        % Button pushed function: StartImageButton
        function StartImageButtonPushed(app, event)
            disp('start:');
            if app.dirname == ""
                f=msgbox('Please set the save directory.','warning');
                return;
            end
            if app.W561nmCheckBox.Value == 0 && app.W488nmCheckBox.Value==0 && app.W640nmCheckBox.Value==0
                f=msgbox('Please choose at least one color.','warning');
                return;
            end
            if app.startofeachtileEditField.Value == 0 || app.endofeachtileEditField.Value == 0
                f=msgbox('choose start and end pos.','warning');
                return;
            end
            % for temporary debug
            fwrite(app.controlParameters.afg, ':source2:function DC');
            fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.afg2_offset),'mv']);
            fwrite(app.controlParameters.afg, ':output2 on;');
            % do calibration first
            outputSingleScan(app.controlParameters.s1,[4,0,0,0]);
            %calibration_eachtile(app);
            startpos = round(app.startofeachtileEditField.Value,-1);
            endpos = round(app.endofeachtileEditField.Value,-1);
            
            app.waitbar = uifigure;
            app.wait_status = uiprogressdlg(app.waitbar,'Title','Take images','Message','Begining','Cancelable','on');
            
            % start camera
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',10);
            %zero_image = uint16(zeros(2048,2048));
            
           try
               for tile_index =1:size(app.xyzScanArray,2)
                   app.controlParameters.MMC.stopSequenceAcquisition;
                   app.controlParameters.MMC.clearCircularBuffer;
                   app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow); 
                   if app.current_path == 1
                       outputSingleScan(app.controlParameters.s1,[4,0,0,0]);
                       outputSingleScan(app.controlParameters.s2,[0,0,0,0]);
                       SetAfgRamp(app.controlParameters.afg,app.state_afg.Bottom_mv_(2),app.state_afg.Top_mv_(2),600,50,1);
                       fwrite(app.controlParameters.afg, [':source1:voltage:offset ',num2str(app.state_afg.Offset_mv_(2)),'mv']);
                       fwrite(app.controlParameters.afg, ':output1 on;');
                   else
                       outputSingleScan(app.controlParameters.s2,[4,0,0,0]);
                       outputSingleScan(app.controlParameters.s1,[0,0,0,0]);
                       %%
                   end
                   
                   pause(1);
                   if app.wait_status.CancelRequested
                       break;
                   end
                   if app.W488nmCheckBox.Value == 1
                       app.status_488nm=true;
                       SwitchOnOffObis(app.allLasers(1).serialPort,1);
                       SwitchOnOffObis(app.allLasers(2).serialPort,0);
                       SwitchOnOffObis(app.allLasers(3).serialPort,0);
                       fprintf(app.controlParameters.sFWDet, 'pos=2');
                       pause(10);
                       [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                       dx = app.xyzScanArray(1,tile_index) - xPos;
                       dy = startpos-yPos; 
                       dz = app.xyzScanArray(3,tile_index) - zPos;
                       SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
                       
                       saving_dir_488nm = [app.dirname,'/','488nm'];
                       [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                       firstaxis = num2str(round(xPos+30000,-1)*10,'%06d');
                       secondaxis = num2str(round(zPos+30000,-1)*10,'%06d');
                       filefoldername = [saving_dir_488nm,'/',firstaxis,'/',firstaxis,'_',secondaxis,'/'];
                       if ~exist(filefoldername)
                           mkdir(filefoldername)
                       end
                       % calculate the interpolate
%                        for i = 1:app.CheckPointsEditField.Value
%                            ypos(i) = app.checkpoints_multipletile(tile_index,i).yPos_488nm;
%                            Detectionlens(i) = app.checkpoints_multipletile(tile_index,i).Detectionlens_488nm;
%                        end
                       positions = round(startpos:app.zstack_distanceum.Value:endpos);
                       %Detectionlens_pos = interp1(ypos,Detectionlens,positions,'linear','extrap');
                       [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                       fprintf(app.controlParameters.sDetLens,['1PA',num2str(app.offset_detectionlens_488nm)]);
                       for i=1:numel(positions)
                           [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                           thirdaxis = num2str(round(yPos+30000)*10,'%06d');
                           SetRelativePos(app.controlParameters.MMC,0,positions(i)-yPos,0);
%                            if Detectionlens_pos(i)> app.offset_detectionlens_488nm+0.06
%                                Detectionlens_pos(i) = app.offset_detectionlens_488nm+0.06;
%                            end
%                            if Detectionlens_pos(i) < app.offset_detectionlens_488nm-0.06
%                                Detectionlens_pos(i) = app.offset_detectionlens_488nm-0.06;
%                            end
                           %fprintf(app.controlParameters.sDetLens,['1PA',num2str(Detectionlens_pos(i))]);
                           %disp(['488: ',num2str(Detectionlens_pos(i))]);
                           %fprintf(app.controlParameters.sDetLens,['1PA',num2str(-0.014)]);
                           % save images
                            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                                if app.controlParameters.MMC.getRemainingImageCount()>0
                                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                                    width = app.controlParameters.MMC.getImageWidth();
                                    height = app.controlParameters.MMC.getImageHeight();
                                    pixelType = 'uint16';
                                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                                    Image = rot90(image);
                                    image_path = [filefoldername,'/',firstaxis,'_',secondaxis,'_',thirdaxis,'.tiff'];
                                    imwrite(Image,image_path);
                                    if mod(i,30)==0 || i == 2
                                        if var(single(Image(:)))>35
                                            calibration_once(app);
                                            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
                                        end
                                    end
                                    break;
                                end
                            end
                            
                            % update waitbar
                            app.wait_status.Value = i/numel(positions);
                            app.wait_status.Message = ['Tile ',num2str(tile_index),'/',num2str(size(app.xyzScanArray,2)),' 488nm ',' Images ',num2str(i),'/',num2str(numel(positions))];
                            if app.wait_status.CancelRequested
                                break;
                            end
                            
                       end
                       % add zero images
                        
                        %start_min = min([app.checkpoints_multipletile(:).startpos]);
                        %end_max = max([app.checkpoints_multipletile(:).endpos]);
%                         for index = start_min:app.zstack_distanceum.Value:end_max
%                             if ~exist([filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'],'file')
%                                 % add zero images
%                                 imwrite(zero_image,[filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'])
%                             else
%                                 continue;
%                             end
%                         end
                        app.status_488nm=false;     
                   end
                   if app.W561nmCheckBox.Value == 1
                       app.status_561nm=true;
                       SwitchOnOffObis(app.allLasers(1).serialPort,0);
                       SwitchOnOffObis(app.allLasers(2).serialPort,1);
                       SwitchOnOffObis(app.allLasers(3).serialPort,0);
                       fprintf(app.controlParameters.sFWDet, 'pos=3');
                       pause(10);
                       [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                       dx = app.xyzScanArray(1,tile_index) - xPos;
                       dy = startpos-yPos; 
                       dz = app.xyzScanArray(3,tile_index) - zPos;
                       SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
                       
                       saving_dir_561nm = [app.dirname,'/','561nm'];
                       [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                       firstaxis = num2str(round(xPos+30000,-1)*10,'%06d');
                       secondaxis = num2str(round(zPos+30000,-1)*10,'%06d');
                       filefoldername = [saving_dir_561nm,'/',firstaxis,'/',firstaxis,'_',secondaxis,'/'];
                       if ~exist(filefoldername)
                           mkdir(filefoldername)
                       end
                       % calculate the interpolate
                       
                       positions = round(startpos:app.zstack_distanceum.Value:endpos);
                       %Detectionlens_pos = interp1(ypos,Detectionlens,positions,'linear','extrap');
                       [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                       fprintf(app.controlParameters.sDetLens,['1PA',num2str(app.offset_detectionlens_561nm)]);
                       for i=1:numel(positions)
                           [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                           thirdaxis = num2str(round(yPos+30000)*10,'%06d');                           
                           SetRelativePos(app.controlParameters.MMC,0,positions(i)-yPos,0);
                           
                           %fprintf(app.controlParameters.sDetLens,['1PA',num2str(Detectionlens_pos(i))]);
                           %disp(['561: ',num2str(Detectionlens_pos(i))]);
                           %fprintf(app.controlParameters.sDetLens,['1PA',num2str(-0.014)]);
                           % save images
                            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                                if app.controlParameters.MMC.getRemainingImageCount()>0
                                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                                    width = app.controlParameters.MMC.getImageWidth();
                                    height = app.controlParameters.MMC.getImageHeight();
                                    pixelType = 'uint16';
                                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                                    Image = rot90(image);
                                    image_path = [filefoldername,'/',firstaxis,'_',secondaxis,'_',thirdaxis,'.tiff'];
                                    imwrite(Image,image_path);
                                    if mod(i,30)==0 || i == 2
                                        if var(single(Image(:)))>35
                                            calibration_once(app);
                                            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow); 
                                        end
                                    end
                                    break;
                                end
                            end
                            % update waitbar
                            app.wait_status.Value = i/numel(positions);
                            app.wait_status.Message = ['Tile ',num2str(tile_index),'/',num2str(size(app.xyzScanArray,2)),' 561nm ',' Images ',num2str(i),'/',num2str(numel(positions))];
                            if app.wait_status.CancelRequested
                                break;
                            end
                            
                       end
                       % add zero images
%                         if app.checkpoints_multipletile(tile_index,1).current_path == 1
%                             outputSingleScan(app.controlParameters.s1,[4,0,0,0]);
%                             outputSingleScan(app.controlParameters.s2,[0,0,0,0]);
%                         elseif app.checkpoints_multipletile(tile_index,1).current_path == 2
%                             outputSingleScan(app.controlParameters.s2,[0,GalvoX_pos(1),GalvoY_pos(1),GalvoZ_pos(1)])
%                             outputSingleScan(app.controlParameters.s1,[0,GalvoX_pos(1),GalvoY_pos(1),GalvoZ_pos(1)])
%                         end
%                         start_min = min([app.checkpoints_multipletile(:).startpos]);
%                         end_max = max([app.checkpoints_multipletile(:).endpos]);
%                         for index = start_min:app.zstack_distanceum.Value:end_max
%                             if ~exist([filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'],'file')
%                                 % add zero images
%                                 imwrite(zero_image,[filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'])
%                             else
%                                 continue;
%                             end
%                         end
                        app.status_561nm=false;
                   end
                   if app.wait_status.CancelRequested
                       break;
                   end
                   if app.W640nmCheckBox.Value == 1
                       app.status_640nm=true;
                       SwitchOnOffObis(app.allLasers(1).serialPort,0);
                       SwitchOnOffObis(app.allLasers(2).serialPort,0);
                       SwitchOnOffObis(app.allLasers(3).serialPort,1);
                       fprintf(app.controlParameters.sFWDet, 'pos=4');
                       pause(10);
                       [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                       dx = app.xyzScanArray(1,tile_index) - xPos;
                       dy = startpos-yPos; 
                       dz = app.xyzScanArray(3,tile_index) - zPos;
                       SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
                       
                       saving_dir_640nm = [app.dirname,'/','640nm'];
                       [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                       firstaxis = num2str(round(xPos+30000,-1)*10,'%06d');
                       secondaxis = num2str(round(zPos+30000,-1)*10,'%06d');
                       filefoldername = [saving_dir_640nm,'/',firstaxis,'/',firstaxis,'_',secondaxis,'/'];
                       if ~exist(filefoldername)
                           mkdir(filefoldername)
                       end
                       
                       positions = round(startpos:app.zstack_distanceum.Value:endpos);
                       
                       [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                       fprintf(app.controlParameters.sDetLens,['1PA',num2str(app.offset_detectionlens_640nm)]);
                       for i=1:numel(positions)
                           [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
                           thirdaxis = num2str(round(yPos+30000)*10,'%06d');
                           SetRelativePos(app.controlParameters.MMC,0,positions(i)-yPos,0);
                           
                           
                           %disp(['640: ',num2str(Detectionlens_pos(i))]);
                           %fprintf(app.controlParameters.sDetLens,['1PA',num2str(-0.014)]);
                           % save images
                            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                                if app.controlParameters.MMC.getRemainingImageCount()>0
                                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                                    width = app.controlParameters.MMC.getImageWidth();
                                    height = app.controlParameters.MMC.getImageHeight();
                                    pixelType = 'uint16';
                                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                                    Image = rot90(image);
                                    image_path = [filefoldername,'/',firstaxis,'_',secondaxis,'_',thirdaxis,'.tiff'];
                                    imwrite(Image,image_path);
                                    if mod(i,30)==0 || i == 2
                                        if var(single(Image(:)))>35
                                            calibration_once(app);
                                            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow); 
                                        end
                                    end
                                    break;
                                end
                            end
                            % update waitbar
                            app.wait_status.Value = i/numel(positions);
                            app.wait_status.Message = ['Tile ',num2str(tile_index),'/',num2str(size(app.xyzScanArray,2)),' 640nm ',' Images ',num2str(i),'/',num2str(numel(positions))];
                            if app.wait_status.CancelRequested
                                break;
                            end
                            
                       end
%                        % add zero images
%                         if app.checkpoints_multipletile(tile_index,1).current_path == 1
%                             outputSingleScan(app.controlParameters.s1,[4,0,0,0]);
%                             outputSingleScan(app.controlParameters.s2,[0,0,0,0]);
%                         elseif app.checkpoints_multipletile(tile_index,1).current_path == 2
%                             outputSingleScan(app.controlParameters.s2,[0,GalvoX_pos(1),GalvoY_pos(1),GalvoZ_pos(1)])
%                             outputSingleScan(app.controlParameters.s1,[0,GalvoX_pos(1),GalvoY_pos(1),GalvoZ_pos(1)])
%                         end
%                         start_min = min([app.checkpoints_multipletile(:).startpos]);
%                         end_max = max([app.checkpoints_multipletile(:).endpos]);
%                         for index = start_min:app.zstack_distanceum.Value:end_max
%                             if ~exist([filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'],'file')
%                                 % add zero images
%                                 imwrite(zero_image,[filefoldername,'/',firstaxis,'_',secondaxis,'_',num2str((round(index)+30000)*10),'.tiff'])
%                             else
%                                 continue;
%                             end
%                         end
                        app.status_640nm=false;
                   end
               end
               SwitchOnOffObis(app.allLasers(1).serialPort,0);
               SwitchOnOffObis(app.allLasers(2).serialPort,0);
               SwitchOnOffObis(app.allLasers(3).serialPort,0);
               [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
               dx = app.xinitPos-xPos;dy=app.yinitPos-yPos;dz=app.zinitPos-zPos;
               SetRelativePos(app.controlParameters.MMC,dx,dy,dz);
               app.controlParameters.MMC.stopSequenceAcquisition;
               app.controlParameters.MMC.clearCircularBuffer;
               close(app.waitbar);
               close(app.wait_status);
           catch err
               app.controlParameters.MMC.stopSequenceAcquisition;
               app.controlParameters.MMC.clearCircularBuffer;
               disp(err.message);
                
               SwitchOnOffObis(app.allLasers(1).serialPort,0);
               SwitchOnOffObis(app.allLasers(2).serialPort,0);
               SwitchOnOffObis(app.allLasers(3).serialPort,0);
               close(app.waitbar);
               close(app.wait_status);
           end
        end

        % Value changed function: PaintButton
        function PaintButtonValueChanged(app, event)
            value = app.PaintButton.Value;
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            % the idea is first generate a very large matrix, and put the
            % inital image in the middle and move stage to add other
            % images, and crop image with value.
            if value == 1
                [app.paint_initx,app.paint_inity,app.paint_initz]=getXYZposition(app.controlParameters.MMC);
                app.paint = figure;
                app.paint.Position = [100 100 1024 1024];
                %set(app.f,'toolbar','figure');
                %hAxes = subplot(1,1,1);
                % start camear and grap initial image;
                app.controlParameters.MMC.stopSequenceAcquisition;
                app.controlParameters.MMC.clearCircularBuffer;
                app.controlParameters.MMC.setCircularBufferMemoryFootprint(1024*10);
                numOfImages = 1000000;
                intervalMs = 0;
                stopOnOverflow = false;
                app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',10);
                app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
                % save images
                while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                    if app.controlParameters.MMC.getRemainingImageCount()>0
                        image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                        width = app.controlParameters.MMC.getImageWidth();
                        height = app.controlParameters.MMC.getImageHeight();
                        pixelType = 'uint16';
                        image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                        image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                        image = rot90(image);                        
                        %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
                        break;
                     end
                end
                 app.paint_init_image = zeros(2048*21,2048*21);
                 app.paint_init_image(2048*10+1:2048*11,2048*10+1:2048*11) = image;
                 % crop image
                 [row_,col_] = find(app.paint_init_image>0);
                 min_row = min(row_);
                 max_row = max(row_);
                 min_col = min(col_);
                 max_col = max(col_);
                 
                 image_cropped = app.paint_init_image(min_row:max_row,min_col:max_col);
                 %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
                 hImage = imshow(image_cropped,'DisplayRange', [0,app.Imagecontrast.Value]);
                 images.roi.Circle(gca,'Center',[(min_col+max_col)/2,(min_row+max_row)/2],'Radius',200,'Color',[0 1 0],'LineWidth',3);
                 vertical_tile = (max_row-min_row)/2048;disp(['vertical tile: ',num2str(vertical_tile)]);
            horizontal_tile = (max_col-min_col)/2048;disp(['horizontal tile: ', num2str(horizontal_tile)]);
            title(['vertical tile: ',num2str(vertical_tile),' horizontal tile: ',num2str(horizontal_tile)]);
                 app.controlParameters.MMC.stopSequenceAcquisition;
                 app.controlParameters.MMC.clearCircularBuffer;
            elseif value == 0
                close(app.paint);
            end
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            if app.Stage_step_X < 40
                f=msgbox('choose a bigger step size.','warning');
                return;
            end
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
%             if app.Stage_step_X > 1100
%                 f=msgbox('choose a smaller step size.','warning');
%                 return;
%             end
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',-app.Stage_step_X,0);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            app.state_stage.X = app.controlParameters.MMC.getXPosition();
            app.UITable_stage.Data = app.state_stage;
            % update image
            %% get new image
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',20);
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            % new image pos
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                if app.controlParameters.MMC.getRemainingImageCount()>0
                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                    width = app.controlParameters.MMC.getImageWidth();
                    height = app.controlParameters.MMC.getImageHeight();
                    pixelType = 'uint16';
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    new_image = rot90(image);
                    %figure(2)
                    %imshow(new_image,[0,5000])
                    break;
                end
            end
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            %% merge image
            % calculate pixel movement
            dx = xPos - app.paint_initx;
            dz = zPos - app.paint_initz;
            d_pixel_x = round(dx/0.65);
            d_pixel_z = round(dz/0.65);
            app.paint_init_image(2048*10+1+d_pixel_x:2048*10+1+d_pixel_x+2047,2048*10+1-d_pixel_z:2048*10+1-d_pixel_z+2047)=new_image;
            [row_,col_] = find(app.paint_init_image>0);
            min_row = min(row_);
            max_row = max(row_);
            min_col = min(col_);
            max_col = max(col_);
           
            image_cropped = app.paint_init_image(min_row:max_row,min_col:max_col);
            %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
            hImage = imshow(image_cropped,'DisplayRange', [0,app.Imagecontrast.Value]);
            images.roi.Circle(gca,'Center',[2048*10+1-d_pixel_z+1024-min_col,2048*10+1+d_pixel_x+1024-min_row],'Radius',400,'Color',[0 1 0],'LineWidth',3,'FaceAlpha',0.01);
            images.roi.Line(gca,'Position',[(max_col-min_col)/2,1;(max_col-min_col)/2,max_row-min_row],'LineWidth',0.2);
            images.roi.Line(gca,'Position',[1,(max_row-min_row)/2;max_col-min_col,(max_row-min_row)/2],'LineWidth',0.2);
            vertical_tile = (max_row-min_row)/2048;disp(['vertical tile: ',num2str(vertical_tile)]);
            horizontal_tile = (max_col-min_col)/2048;disp(['horizontal tile: ', num2str(horizontal_tile)]);
            title(['vertical tile: ',num2str(vertical_tile),' horizontal tile: ',num2str(horizontal_tile)]);
            
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            if app.Stage_step_X < 40
                f=msgbox('choose a bigger step size.','warning');
                return;
            end
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
%             if app.Stage_step_X > 1100
%                 f=msgbox('choose a smaller step size.','warning');
%                 return;
%             end
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',app.Stage_step_X,0);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            app.state_stage.X = app.controlParameters.MMC.getXPosition();
            app.UITable_stage.Data = app.state_stage;
            % update image
            %% get new image
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',20);
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            % new image pos
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                if app.controlParameters.MMC.getRemainingImageCount()>0
                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                    width = app.controlParameters.MMC.getImageWidth();
                    height = app.controlParameters.MMC.getImageHeight();
                    pixelType = 'uint16';
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    new_image = rot90(image);                                            
                    break;
                end
            end
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            %% merge image
            % calculate overlap
            dx = xPos - app.paint_initx;
            dz = zPos - app.paint_initz;
            d_pixel_x = round(dx/0.65);
            d_pixel_z = round(dz/0.65);
            app.paint_init_image(2048*10+1+d_pixel_x:2048*10+1+d_pixel_x+2047,2048*10+1-d_pixel_z:2048*10+1-d_pixel_z+2047)=new_image;
            [row_,col_] = find(app.paint_init_image>0);
            min_row = min(row_);
            max_row = max(row_);
            min_col = min(col_);
            max_col = max(col_);
                 
            image_cropped = app.paint_init_image(min_row:max_row,min_col:max_col);
            %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
            hImage = imshow(image_cropped,'DisplayRange', [0,app.Imagecontrast.Value]);
            images.roi.Circle(gca,'Center',[2048*10+1-d_pixel_z+1024-min_col,2048*10+1+d_pixel_x+1024-min_row],'Radius',400,'Color',[0 1 0],'LineWidth',3,'FaceAlpha',0.01);
            images.roi.Line(gca,'Position',[(max_col-min_col)/2,1;(max_col-min_col)/2,max_row-min_row],'LineWidth',0.2);
            images.roi.Line(gca,'Position',[1,(max_row-min_row)/2;max_col-min_col,(max_row-min_row)/2],'LineWidth',0.2);
            vertical_tile = (max_row-min_row)/2048;disp(['vertical tile: ',num2str(vertical_tile)]);
            horizontal_tile = (max_col-min_col)/2048;disp(['horizontal tile: ', num2str(horizontal_tile)]);
            title(['vertical tile: ',num2str(vertical_tile),' horizontal tile: ',num2str(horizontal_tile)]);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            app.controlParameters.MMC.setRelativePosition('ZStage:Z:32',app.Stage_step_Z);
            app.controlParameters.MMC.waitForDevice('ZStage:Z:32');
            app.state_stage.Z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.UITable_stage.Data = app.state_stage;
            % update image
            %% get new image
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',20);
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            % new image pos
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                if app.controlParameters.MMC.getRemainingImageCount()>0
                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                    width = app.controlParameters.MMC.getImageWidth();
                    height = app.controlParameters.MMC.getImageHeight();
                    pixelType = 'uint16';
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    new_image = rot90(image);                                            
                    break;
                end
            end
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            %% merge image
            % calculate
            dx = xPos - app.paint_initx;
            dz = zPos - app.paint_initz;
            d_pixel_x = round(dx/0.65);
            d_pixel_z = round(dz/0.65);
            app.paint_init_image(2048*10+1+d_pixel_x:2048*10+1+d_pixel_x+2047,2048*10+1-d_pixel_z:2048*10+1-d_pixel_z+2047)=new_image;
            [row_,col_] = find(app.paint_init_image>0);
            min_row = min(row_);
            max_row = max(row_);
            min_col = min(col_);
            max_col = max(col_);
                 
            image_cropped = app.paint_init_image(min_row:max_row,min_col:max_col);
            %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
            hImage = imshow(image_cropped,'DisplayRange', [0,app.Imagecontrast.Value]);
            images.roi.Circle(gca,'Center',[2048*10+1-d_pixel_z+1024-min_col,2048*10+1+d_pixel_x+1024-min_row],'Radius',400,'Color',[0 1 0],'LineWidth',3,'FaceAlpha',0.01);
            images.roi.Line(gca,'Position',[(max_col-min_col)/2,1;(max_col-min_col)/2,max_row-min_row],'LineWidth',0.2);
            images.roi.Line(gca,'Position',[1,(max_row-min_row)/2;max_col-min_col,(max_row-min_row)/2],'LineWidth',0.2);
            vertical_tile = (max_row-min_row)/2048;disp(['vertical tile: ',num2str(vertical_tile)]);
            horizontal_tile = (max_col-min_col)/2048;disp(['horizontal tile: ', num2str(horizontal_tile)]);
            title(['vertical tile: ',num2str(vertical_tile),' horizontal tile: ',num2str(horizontal_tile)]);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            if app.StatusLamp.Color(2) == 1
                disp('the status is green, unable to work.');
                return;
            end
            app.controlParameters.MMC.setRelativePosition('ZStage:Z:32',-app.Stage_step_Z);
            app.controlParameters.MMC.waitForDevice('ZStage:Z:32');
            app.state_stage.Z = app.controlParameters.MMC.getPosition('ZStage:Z:32');
            app.UITable_stage.Data = app.state_stage;
            % update image
            %% get new image
            numOfImages = 1000000;
            intervalMs = 0;
            stopOnOverflow = false;
            app.controlParameters.MMC.setProperty('HamamatsuHam_DCAM','Exposure',20);
            app.controlParameters.MMC.startSequenceAcquisition(numOfImages, intervalMs, stopOnOverflow);
            % new image pos
            [xPos,yPos,zPos]=getXYZposition(app.controlParameters.MMC);
            while(app.controlParameters.MMC.getRemainingImageCount()>0 || app.controlParameters.MMC.isSequenceRunning())
                if app.controlParameters.MMC.getRemainingImageCount()>0
                    image = app.controlParameters.MMC.getLastImage();  % returned as a 1D array of signed integers in row-major order
                    width = app.controlParameters.MMC.getImageWidth();
                    height = app.controlParameters.MMC.getImageHeight();
                    pixelType = 'uint16';
                    image = typecast(image, pixelType);      % pixels must be interpreted as unsigned integers
                    image = reshape(image, [width, height]); % image should be interpreted as a 2D array
                    new_image = rot90(image);                                            
                    break;
                end
            end
            app.controlParameters.MMC.stopSequenceAcquisition;
            app.controlParameters.MMC.clearCircularBuffer;
            %% merge image
            % calculate overlap
            dx = xPos - app.paint_initx;
            dz = zPos - app.paint_initz;
            d_pixel_x = round(dx/0.65);
            d_pixel_z = round(dz/0.65);
            app.paint_init_image(2048*10+1+d_pixel_x:2048*10+1+d_pixel_x+2047,2048*10+1-d_pixel_z:2048*10+1-d_pixel_z+2047)=new_image;
            [row_,col_] = find(app.paint_init_image>0);
            min_row = min(row_);
            max_row = max(row_);
            min_col = min(col_);
            max_col = max(col_);
                 
            image_cropped = app.paint_init_image(min_row:max_row,min_col:max_col);
            %hImage = imshow(app.paint_init_image,'Parent',hAxes, 'DisplayRange', [0,app.Imagecontrast.Value]);
            hImage = imshow(image_cropped,'DisplayRange', [0,app.Imagecontrast.Value]);
            images.roi.Circle(gca,'Center',[2048*10+1-d_pixel_z+1024-min_col,2048*10+1+d_pixel_x+1024-min_row],'Radius',400,'Color',[0 1 0],'LineWidth',3,'FaceAlpha',0.01);
            images.roi.Line(gca,'Position',[(max_col-min_col)/2,1;(max_col-min_col)/2,max_row-min_row],'LineWidth',0.2);
            images.roi.Line(gca,'Position',[1,(max_row-min_row)/2;max_col-min_col,(max_row-min_row)/2],'LineWidth',0.2);
            vertical_tile = (max_row-min_row)/2048;disp(['vertical tile: ',num2str(vertical_tile)]);
            horizontal_tile = (max_col-min_col)/2048;disp(['horizontal tile: ', num2str(horizontal_tile)]);
            title(['vertical tile: ',num2str(vertical_tile),' horizontal tile: ',num2str(horizontal_tile)]);
        end

        % Value changed function: RollScanButton
        function RollScanButtonValueChanged(app, event)
            value = app.RollScanButton.Value;
            if value == 1
                index = 2;
                fwrite(app.controlParameters.afg, ':output2 off;');
                if app.current_path == 1
                    if app.RollSlider.Value > 0
                        SetAfgRamp_roll(app.controlParameters.afg,app.RollSlider.Value*app.state_afg.Bottom_mv_(index),app.RollSlider.Value*app.state_afg.Top_mv_(index),600,50,1);
                        fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.state_afg.Offset_mv_(2)),'mv']);
                        fwrite(app.controlParameters.afg, [':source2:phase:adjust 0deg']);
                    elseif app.RollSlider.Value == 0
                        SetAfgRamp_roll(app.controlParameters.afg,0,1,600,50,1);
                        fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.state_afg.Offset_mv_(2)),'mv']);
                        fwrite(app.controlParameters.afg, [':source2:phase:adjust 0deg']);
                    elseif app.RollSlider.Value < 0
                        SetAfgRamp_roll(app.controlParameters.afg,-app.RollSlider.Value*app.state_afg.Bottom_mv_(index),-app.RollSlider.Value*app.state_afg.Top_mv_(index),600,50,1);
                        fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.state_afg.Offset_mv_(2)),'mv']);
                        fwrite(app.controlParameters.afg, [':source2:phase:adjust 180deg']);
                        
                    end
                    fwrite(app.controlParameters.afg, ':source1:phase:initiate');
                    fwrite(app.controlParameters.afg, ':output2 on;');
                end
            elseif value == 0
                %fwrite(app.controlParameters.afg, ':output2 off;');
                fwrite(app.controlParameters.afg, ':source2:function DC');
                fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.afg2_offset),'mv']);
                
                fwrite(app.controlParameters.afg, ':output2 on;');
            end
        end

        % Value changed function: RollSlider
        function RollSliderValueChanged(app, event)
            value = app.RollSlider.Value;
            if app.RollScanButton.Value == 0
                app.RollSlider.Value = 0;
                return;
            end
            index =2;
            if value > 0
                SetAfgRamp_roll(app.controlParameters.afg,value*app.state_afg.Bottom_mv_(index),app.RollSlider.Value*app.state_afg.Top_mv_(index),600,50,1);
                fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.state_afg.Offset_mv_(2)),'mv']);
                fwrite(app.controlParameters.afg, [':source2:phase:adjust 0deg']);
            elseif value == 0
                return;
                SetAfgRamp_roll(app.controlParameters.afg,0,1,600,50,1);
                fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.state_afg.Offset_mv_(2)),'mv']);
                %fwrite(afg, [':source2:phase:adjust 0deg']);
            elseif value < 0
                SetAfgRamp_roll(app.controlParameters.afg,-value*app.state_afg.Bottom_mv_(index),-value*app.state_afg.Top_mv_(index),600,50,1);
                fwrite(app.controlParameters.afg, [':source2:voltage:offset ',num2str(app.state_afg.Offset_mv_(2)),'mv']);
                fwrite(app.controlParameters.afg, [':source2:phase:adjust 180deg']);
            end
            fwrite(app.controlParameters.afg, ':source1:phase:initiate');
            fwrite(app.controlParameters.afg, ':output2 on;');
        end

        % Button pushed function: testboundaryButton
        function testboundaryButtonPushed(app, event)
            %app.a = 3.4;
            %app.a
            return;
            image_score = [];
            detect_pos = [-0.06:0.002:0.06];
            for j = 1:length(detect_pos)
                fprintf(app.controlParameters.sDetLens,['1PA',num2str(detect_pos(j)+app.offset_detectionlens)]);
                pause(0.5);
                Image = requestoneimagefromcamera(app);
                imwrite(Image,[app.dirname,'/','img',num2str(j),'.tiff'])
                %image_score = [image_score,var(single(Image(:)))];
            end


            return;
            [startpos,endpos] = getboundary(app);
            startpos
            endpos
            return;
            %Image = requestoneimagefromcamera(app);
            %var(single(Image(:)))
            %return;
            image_content_vector = [];
            image_pos_vector = [];
            initial_step = 200;
            current_y = app.controlParameters.MMC.getYPosition();%22000
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,12800-current_y);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            current_y = app.controlParameters.MMC.getYPosition();
            %move to start position
            % start pos is 6800 end pos is 20000
            startpos=6800;endpos=18000;
            if current_y - initial_step < startpos
                f=msgbox('Reach the limit.','warning');
                return;
            end
            if current_y + initial_step > endpos
                f=msgbox('Reach the limit.','warning');
                return;
            end            
            app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,startpos-current_y);
            app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            % start go through from deep to surface (6600 to 20000) 
            for i = 1:100
                current_y = app.controlParameters.MMC.getYPosition();
                if current_y + initial_step > endpos
                    break;
                end
                Image = requestoneimagefromcamera(app);
                %imwrite(Image,['testimages/','num2str(i)','.tiff'])
                if var(single(Image(:)))>15
                    image_content_vector = [image_content_vector,1];
                    image_pos_vector = [image_pos_vector,current_y];
                    %imwrite(Image,['testimages/',num2str(i),'_1_',num2str(var(single(Image(:)))),'.tiff'])
                else
                    image_content_vector = [image_content_vector,0];
                    image_pos_vector = [image_pos_vector,current_y];
                    %imwrite(Image,['testimages/',num2str(i),'_0_',num2str(var(single(Image(:)))),'.tiff'])
                end
                app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,200);
                app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
            end
            %return;
%             image_content_vector
%             image_pos_vector
%             return;
            %image_content_vector =
            % output the boundary of sample on this tile
            if sum(image_content_vector) == 0
                start_tile = 11600
                end_tile = 11720
                return;
            elseif sum(image_content_vector) == 1
                middle_tile = int32(image_pos_vector(image_content_vector>0));
            elseif sum(image_content_vector) > 1
                index = find(image_content_vector>0);
                start_pos = image_pos_vector(index(1));
                end_pos = image_pos_vector(index(end));
                middle_tile = int32((start_pos+end_pos)/2);
            end
            image_pos_vector(image_content_vector>0); 
            % find the start pos and end pos for each tile
            %% find start pos
            index = find(image_content_vector>0);
            if index(1)==1
                start_tile=startpos;
            else
                pos1 = image_pos_vector(index(1));pos2=image_pos_vector(index(1)-1);
                for i=1:8
                    mid_pos = round((pos1+pos2)/2);
                    current_y = app.controlParameters.MMC.getYPosition();
                    app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,mid_pos-current_y);
                    app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
                    Image = requestoneimagefromcamera(app);
                    current_y = app.controlParameters.MMC.getYPosition();
                    if var(single(Image(:)))>15
                        pos1 = round(current_y);
                    else
                        pos2 = round(current_y);
                    end
                end
                start_tile = round(pos2);
            end
            %%
            %% find end pos
            if index(end)==length(image_content_vector)
                end_tile = endpos;
            else
                pos1 = image_pos_vector(index(end));pos2 = image_pos_vector(index(end)+1);
                for i=1:8
                    mid_pos = round((pos1+pos2)/2);
                    current_y = app.controlParameters.MMC.getYPosition();
                    app.controlParameters.MMC.setRelativeXYPosition('XYStage:XY:31',0,mid_pos-current_y);
                    app.controlParameters.MMC.waitForDevice('XYStage:XY:31');
                    Image = requestoneimagefromcamera(app);
                    current_y = app.controlParameters.MMC.getYPosition();
                    if var(single(Image(:)))>15
                        pos1 = round(current_y);
                    else
                        pos2 = round(current_y);
                    end
                end
                end_tile = round(pos2);
            end
            %%
            image_pos_vector
            image_content_vector
            start_tile
            end_tile
        end

        % Button pushed function: SetoffsetofdetectionlensButton
        function SetoffsetofdetectionlensButtonPushed(app, event)
%             app.offset_detectionlens_488nm
%             app.offset_detectionlens_561nm
%             app.offset_detectionlens_640nm
%             return;
            fprintf(app.controlParameters.sDetLens,'1TP?');
            pos = fscanf(app.controlParameters.sDetLens);
            pos=getpos(pos);
            app.offset_detectionlens = str2num(pos);
            if convertCharsToStrings(app.Lightwavelight.Value) == '488nm'
                fprintf(app.controlParameters.sDetLens,'1TP?');
                pos = fscanf(app.controlParameters.sDetLens);
                pos=getpos(pos);
                app.offset_detectionlens_488nm = str2num(pos);
                disp('set offset for 488nm');
            elseif convertCharsToStrings(app.Lightwavelight.Value) == '561nm'
                fprintf(app.controlParameters.sDetLens,'1TP?');
                pos = fscanf(app.controlParameters.sDetLens);
                pos=getpos(pos);
                app.offset_detectionlens_561nm = str2num(pos);
                disp('set offset for 561nm');
            elseif convertCharsToStrings(app.Lightwavelight.Value) == '640nm'
                fprintf(app.controlParameters.sDetLens,'1TP?');
                pos = fscanf(app.controlParameters.sDetLens);
                pos=getpos(pos);
                app.offset_detectionlens_640nm = str2num(pos);                
                disp('set offset for 640nm');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ShutterButton = uibutton(app.UIFigure, 'state');
            app.ShutterButton.ValueChangedFcn = createCallbackFcn(app, @ShutterButtonValueChanged, true);
            app.ShutterButton.Text = 'Shutter';
            app.LightwavelightDropDownLabel = uilabel(app.UIFigure);
            app.LightwavelightDropDownLabel.Text = 'Light wavelight';
            app.Lightwavelight = uidropdown(app.UIFigure);
            app.Lightwavelight.Items = {'None', '488nm', '561nm', '640nm'};
            app.Lightwavelight.ValueChangedFcn = createCallbackFcn(app, @LightwavelightValueChanged, true);
            app.Lightwavelight.Value = 'None';
            app.LaserPowermWEditFieldLabel = uilabel(app.UIFigure);
            app.LaserPowermWEditFieldLabel.Text = 'Laser Power(mW)';
            app.LaserPower = uieditfield(app.UIFigure, 'numeric');
            app.LaserPower.ValueChangedFcn = createCallbackFcn(app, @LaserPowerValueChanged, true);
            app.LaserPower.Value = 2;
            app.galvo_XSliderLabel = uilabel(app.UIFigure);
            app.galvo_XSliderLabel.Text = 'galvo_X';
            app.galvo_XSlider = uislider(app.UIFigure);
            app.galvo_XSlider.Limits = [-0.4 0.4];
            app.galvo_XSlider.MajorTicks = [-0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4];
            app.galvo_XSlider.ValueChangedFcn = createCallbackFcn(app, @galvo_XSliderValueChanged, true);
            app.galvo_YSliderLabel = uilabel(app.UIFigure);
            app.galvo_YSliderLabel.Text = 'galvo_Y';
            app.galvo_YSlider = uislider(app.UIFigure);
            app.galvo_YSlider.Limits = [-0.4 0.4];
            app.galvo_YSlider.MajorTicks = [-0.4 -0.3 -0.2 -0.1 0 0.1 0.2 0.3 0.4];
            app.galvo_YSlider.ValueChangedFcn = createCallbackFcn(app, @galvo_YSliderValueChanged, true);
            app.Reset_galvo_XYButton = uibutton(app.UIFigure, 'push');
            app.Reset_galvo_XYButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_galvo_XYButtonPushed, true);
            app.Reset_galvo_XYButton.Text = 'Reset_galvo_XY';
            app.EnableCam = uibutton(app.UIFigure, 'push');
            app.EnableCam.ButtonPushedFcn = createCallbackFcn(app, @EnableCamButtonPushed, true);
            app.EnableCam.Text = 'Enable Camera';
            app.DisableCam = uibutton(app.UIFigure, 'push');
            app.DisableCam.ButtonPushedFcn = createCallbackFcn(app, @DisableCamButtonPushed, true);
            app.DisableCam.Text = 'Disable Camera';
            app.ImagecontrastLabel = uilabel(app.UIFigure);
            app.ImagecontrastLabel.Text = 'Image contrast';
            app.Imagecontrast = uieditfield(app.UIFigure, 'numeric');
            app.Imagecontrast.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastValueChanged, true);
            app.Imagecontrast.Value = 8000;
            app.FilterwheelDropDownLabel = uilabel(app.UIFigure);
            app.FilterwheelDropDownLabel.Text = 'Filter wheel';
            app.Filterwheel = uidropdown(app.UIFigure);
            app.Filterwheel.Items = {'None', 'Blue', 'Green', 'Red'};
            app.Filterwheel.ValueChangedFcn = createCallbackFcn(app, @FilterwheelValueChanged, true);
            app.Filterwheel.Value = 'None';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '⏫';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '⏬';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '⍟';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '⌾';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '⏪';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '⏩';
            app.SaveImage = uibutton(app.UIFigure, 'push');
            app.SaveImage.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImage.Text = 'Save Image onepath';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '↺';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '↻';
            app.Updown_stepDropDownLabel = uilabel(app.UIFigure);
            app.Updown_stepDropDownLabel.Text = 'Updown_step';
            app.Updown_stepDropDown = uidropdown(app.UIFigure);
            app.Updown_stepDropDown.Items = {'10um', '50um', '100um', '200um', '500um', '1mm', '2mm', '1cm'};
            app.Updown_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @Updown_stepDropDownValueChanged, true);
            app.Updown_stepDropDown.Value = '10um';
            app.Inout_stepDropDownLabel = uilabel(app.UIFigure);
            app.Inout_stepDropDownLabel.Text = 'Inout_step';
            app.Inout_stepDropDown = uidropdown(app.UIFigure);
            app.Inout_stepDropDown.Items = {'10um', '50um', '100um', '200um', '500um', '1mm', '2mm', '1cm'};
            app.Inout_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @Inout_stepDropDownValueChanged, true);
            app.Inout_stepDropDown.Value = '10um';
            app.Leftright_stepDropDownLabel = uilabel(app.UIFigure);
            app.Leftright_stepDropDownLabel.Text = 'Leftright_step';
            app.Leftright_stepDropDown = uidropdown(app.UIFigure);
            app.Leftright_stepDropDown.Items = {'10um', '50um', '100um', '200um', '500um', '1mm', '2mm', '1cm'};
            app.Leftright_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @Leftright_stepDropDownValueChanged, true);
            app.Leftright_stepDropDown.Value = '10um';
            app.Rotation_stepDropDownLabel = uilabel(app.UIFigure);
            app.Rotation_stepDropDownLabel.Text = 'Rotation_step';
            app.Rotation_stepDropDown = uidropdown(app.UIFigure);
            app.Rotation_stepDropDown.Items = {'10um', '50um', '100um', '200um', '500um', '1mm', '2mm'};
            app.Rotation_stepDropDown.ValueChangedFcn = createCallbackFcn(app, @Rotation_stepDropDownValueChanged, true);
            app.Rotation_stepDropDown.Value = '10um';
            app.LIghtpathDropDownLabel = uilabel(app.UIFigure);
            app.LIghtpathDropDownLabel.Text = 'LIght path';
            app.LIghtpathDropDown = uidropdown(app.UIFigure);
            app.LIghtpathDropDown.Items = {'path1', 'path2'};
            app.LIghtpathDropDown.ValueChangedFcn = createCallbackFcn(app, @LIghtpathDropDownValueChanged, true);
            app.LIghtpathDropDown.Value = 'path1';
            app.UITable_stage = uitable(app.UIFigure);
            app.UITable_stage.ColumnName = {'Stage'; 'X'; 'Y'; 'Z'; 'T'};
            app.UITable_stage.RowName = {};
            app.UITable_path = uitable(app.UIFigure);
            app.UITable_path.ColumnName = {'Path'; 'Galvo_X'; 'Galvo_Y'; 'Galvo_Z'};
            app.UITable_path.RowName = {};
            app.UITable_path.ColumnEditable = true;
            app.UITable_path.CellEditCallback = createCallbackFcn(app, @UITable_pathCellEdit, true);
            app.UITable_motor = uitable(app.UIFigure);
            app.UITable_motor.ColumnName = {'Lens'; 'Position(mm)'};
            app.UITable_motor.RowName = {};
            app.UITable_motor.ColumnEditable = true;
            app.UITable_motor.CellEditCallback = createCallbackFcn(app, @UITable_motorCellEdit, true);
            app.UITable_afg = uitable(app.UIFigure);
            app.UITable_afg.ColumnName = {'AFG'; 'Offset(mv)'; 'Top(mv)'; 'Bottom(mv)'};
            app.UITable_afg.RowName = {};
            app.UITable_afg.ColumnEditable = true;
            app.UITable_afg.CellEditCallback = createCallbackFcn(app, @UITable_afgCellEdit, true);
            app.AFG_scanButton = uibutton(app.UIFigure, 'state');
            app.AFG_scanButton.ValueChangedFcn = createCallbackFcn(app, @AFG_scanButtonValueChanged, true);
            app.AFG_scanButton.Text = 'AFG_scan';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SaveImagetwopathButton = uibutton(app.UIFigure, 'push');
            app.SaveImagetwopathButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImagetwopathButtonPushed, true);
            app.SaveImagetwopathButton.Text = 'Save Image twopath';
            app.ImagesavingdirectoryButton = uibutton(app.UIFigure, 'push');
            app.ImagesavingdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ImagesavingdirectoryButtonPushed, true);
            app.ImagesavingdirectoryButton.Text = 'Image saving directory';
            app.savepathEditFieldLabel = uilabel(app.UIFigure);
            app.savepathEditFieldLabel.Text = 'save path';
            app.savepathEditField = uieditfield(app.UIFigure, 'text');
            app.TakingimagesononetilePanel = uipanel(app.UIFigure);
            app.TakingimagesononetilePanel.Title = 'Taking images on one tile';
            app.StateofCheckPointsEditFieldLabel = uilabel(app.TakingimagesononetilePanel);
            app.StateofCheckPointsEditFieldLabel.Text = 'State of CheckPoints';
            app.StateofCheckPointsEditField = uieditfield(app.TakingimagesononetilePanel, 'text');
            app.saveButton = uibutton(app.TakingimagesononetilePanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.saveButton_2 = uibutton(app.TakingimagesononetilePanel, 'push');
            app.saveButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveButton_2Pushed, true);
            app.saveButton_2.Text = 'save';
            app.Start_posEditFieldLabel = uilabel(app.TakingimagesononetilePanel);
            app.Start_posEditFieldLabel.Text = 'Start_pos';
            app.Start_posEditField = uieditfield(app.TakingimagesononetilePanel, 'numeric');
            app.End_posEditFieldLabel = uilabel(app.TakingimagesononetilePanel);
            app.End_posEditFieldLabel.Text = 'End_pos';
            app.End_posEditField = uieditfield(app.TakingimagesononetilePanel, 'numeric');
            app.Check_points1x5Label = uilabel(app.TakingimagesononetilePanel);
            app.Check_points1x5Label.Text = 'Check_points(1<x<5)';
            app.Check_points = uieditfield(app.TakingimagesononetilePanel, 'numeric');
            app.Check_points.Value = 4;
            app.movecheckpointsSpinnerLabel = uilabel(app.TakingimagesononetilePanel);
            app.movecheckpointsSpinnerLabel.Text = 'move checkpoints';
            app.movecheckpointsSpinner = uispinner(app.TakingimagesononetilePanel);
            app.movecheckpointsSpinner.ValueChangedFcn = createCallbackFcn(app, @movecheckpointsSpinnerValueChanged, true);
            app.saveCheckpointsButton = uibutton(app.TakingimagesononetilePanel, 'push');
            app.saveCheckpointsButton.ButtonPushedFcn = createCallbackFcn(app, @saveCheckpointsButtonPushed, true);
            app.saveCheckpointsButton.Text = 'save Check points';
            app.takeonetileimagesButton = uibutton(app.TakingimagesononetilePanel, 'push');
            app.takeonetileimagesButton.ButtonPushedFcn = createCallbackFcn(app, @takeonetileimagesButtonPushed, true);
            app.takeonetileimagesButton.Text = 'take one tile images';
            app.z_distanceumLabel = uilabel(app.TakingimagesononetilePanel);
            app.z_distanceumLabel.Text = 'z_distance(um)';
            app.zstack_distanceum = uieditfield(app.TakingimagesononetilePanel, 'numeric');
            app.zstack_distanceum.Value = 10;
            app.TakingoneimagesonMbyNtilesPanel = uipanel(app.UIFigure);
            app.TakingoneimagesonMbyNtilesPanel.Title = 'Taking one images on M-by-N tiles';
            app.Vertiles_numEditFieldLabel = uilabel(app.TakingoneimagesonMbyNtilesPanel);
            app.Vertiles_numEditFieldLabel.Text = 'Vertiles_num';
            app.Vertiles_num = uieditfield(app.TakingoneimagesonMbyNtilesPanel, 'numeric');
            app.Vertiles_num.Value = 2;
            app.Horztiles_numEditFieldLabel = uilabel(app.TakingoneimagesonMbyNtilesPanel);
            app.Horztiles_numEditFieldLabel.Text = 'Horztiles_num';
            app.Horztiles_num = uieditfield(app.TakingoneimagesonMbyNtilesPanel, 'numeric');
            app.Horztiles_num.Value = 2;
            app.MoveandStitch_2DButton = uibutton(app.TakingoneimagesonMbyNtilesPanel, 'push');
            app.MoveandStitch_2DButton.ButtonPushedFcn = createCallbackFcn(app, @MoveandStitch_2DButtonPushed, true);
            app.MoveandStitch_2DButton.Text = 'MoveandStitch_2D';
            app.TakingimagesonMbyNtileswithzstacksPanel = uipanel(app.UIFigure);
            app.TakingimagesonMbyNtileswithzstacksPanel.Title = 'Taking images on M-by-N tiles with zstacks';
            app.VerticaltileEditFieldLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.VerticaltileEditFieldLabel.Text = 'Vertical tile ';
            app.VerticaltileEditField = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.HorizontaltileEditFieldLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.HorizontaltileEditFieldLabel.Text = 'Horizontal tile';
            app.HorizontaltileEditField = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.CheckPointsEditFieldLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.CheckPointsEditFieldLabel.Text = 'Check Points';
            app.CheckPointsEditField = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.startofeachtileEditFieldLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.startofeachtileEditFieldLabel.Text = 'start of each tile';
            app.startofeachtileEditField = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.saveButton_3 = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.saveButton_3.ButtonPushedFcn = createCallbackFcn(app, @saveButton_3Pushed, true);
            app.saveButton_3.Text = 'save';
            app.endofeachtileEditFieldLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.endofeachtileEditFieldLabel.Text = 'end of each tile';
            app.endofeachtileEditField = uieditfield(app.TakingimagesonMbyNtileswithzstacksPanel, 'numeric');
            app.saveButton_4 = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.saveButton_4.ButtonPushedFcn = createCallbackFcn(app, @saveButton_4Pushed, true);
            app.saveButton_4.Text = 'save';
            app.createscanpatterncenterButton = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.createscanpatterncenterButton.ButtonPushedFcn = createCallbackFcn(app, @createscanpatterncenterButtonPushed, true);
            app.createscanpatterncenterButton.Text = 'create scan pattern(center)';
            app.W488nmCheckBox = uicheckbox(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.W488nmCheckBox.Text = 'W488nm';
            app.W561nmCheckBox = uicheckbox(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.W561nmCheckBox.Text = 'W561nm';
            app.W640nmCheckBox = uicheckbox(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.W640nmCheckBox.Text = 'W640nm';
            app.ChoosewhichcolortoimageLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.ChoosewhichcolortoimageLabel.Text = 'Choose which color to image';
            app.DeepLabel_2 = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.DeepLabel_2.Text = 'Deep';
            app.SurfaceLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.SurfaceLabel.Text = 'Surface';
            app.StatusLampLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.GreensignalwillblockmotionexceptinzaxisLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.GreensignalwillblockmotionexceptinzaxisLabel.Text = 'Green signal will block motion except in z axis';
            app.TileStatesTextAreaLabel = uilabel(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.TileStatesTextAreaLabel.Text = 'Tile States';
            app.TileStatesTextArea = uitextarea(app.TakingimagesonMbyNtileswithzstacksPanel);
            app.StartImageButton = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.StartImageButton.ButtonPushedFcn = createCallbackFcn(app, @StartImageButtonPushed, true);
            app.StartImageButton.Text = 'Start Image';
            app.SetoffsetofdetectionlensButton = uibutton(app.TakingimagesonMbyNtileswithzstacksPanel, 'push');
            app.SetoffsetofdetectionlensButton.ButtonPushedFcn = createCallbackFcn(app, @SetoffsetofdetectionlensButtonPushed, true);
            app.SetoffsetofdetectionlensButton.Text = 'Set offset of detection lens';
            app.ExcitionLensandDetectionLensPanel = uipanel(app.UIFigure);
            app.ExcitionLensandDetectionLensPanel.Title = 'Excition Lens and Detection Lens';
            app.leftButtonExt = uibutton(app.ExcitionLensandDetectionLensPanel, 'push');
            app.leftButtonExt.ButtonPushedFcn = createCallbackFcn(app, @leftButtonExtPushed, true);
            app.leftButtonExt.Text = 'left';
            app.rightButtonExt = uibutton(app.ExcitionLensandDetectionLensPanel, 'push');
            app.rightButtonExt.ButtonPushedFcn = createCallbackFcn(app, @rightButtonExtPushed, true);
            app.rightButtonExt.Text = 'right';
            app.leftButtonObj = uibutton(app.ExcitionLensandDetectionLensPanel, 'push');
            app.leftButtonObj.ButtonPushedFcn = createCallbackFcn(app, @leftButtonObjPushed, true);
            app.leftButtonObj.Text = 'left';
            app.rightButtonObj = uibutton(app.ExcitionLensandDetectionLensPanel, 'push');
            app.rightButtonObj.ButtonPushedFcn = createCallbackFcn(app, @rightButtonObjPushed, true);
            app.rightButtonObj.Text = 'right';
            app.ExtStepsizeDropDownLabel = uilabel(app.ExcitionLensandDetectionLensPanel);
            app.ExtStepsizeDropDownLabel.Text = 'Ext Step size';
            app.ExtStepsizeDropDown = uidropdown(app.ExcitionLensandDetectionLensPanel);
            app.ExtStepsizeDropDown.Items = {'0.1', '0.01', '0.001'};
            app.ExtStepsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtStepsizeDropDownValueChanged, true);
            app.ExtStepsizeDropDown.Value = '0.1';
            app.ObjStepsizeDropDownLabel = uilabel(app.ExcitionLensandDetectionLensPanel);
            app.ObjStepsizeDropDownLabel.Text = 'Obj Step size';
            app.ObjStepsizeDropDown = uidropdown(app.ExcitionLensandDetectionLensPanel);
            app.ObjStepsizeDropDown.Items = {'0.01', '0.005', '0.001'};
            app.ObjStepsizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjStepsizeDropDownValueChanged, true);
            app.ObjStepsizeDropDown.Value = '0.01';
            app.PaintModePanel = uipanel(app.UIFigure);
            app.PaintModePanel.Title = 'Paint Mode';
            app.PaintButton = uibutton(app.PaintModePanel, 'state');
            app.PaintButton.ValueChangedFcn = createCallbackFcn(app, @PaintButtonValueChanged, true);
            app.PaintButton.Text = 'Paint';
            app.UpButton = uibutton(app.PaintModePanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.PaintModePanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.PaintModePanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.PaintModePanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.RollSliderLabel = uilabel(app.UIFigure);
            app.RollSliderLabel.Text = 'Roll';
            app.RollSlider = uislider(app.UIFigure);
            app.RollSlider.Limits = [-0.1 0.1];
            app.RollSlider.MajorTicks = [-0.1 -0.075 -0.05 -0.025 0 0.025 0.05 0.075 0.1];
            app.RollSlider.ValueChangedFcn = createCallbackFcn(app, @RollSliderValueChanged, true);
            app.RollScanButton = uibutton(app.UIFigure, 'state');
            app.RollScanButton.ValueChangedFcn = createCallbackFcn(app, @RollScanButtonValueChanged, true);
            app.RollScanButton.Text = 'Roll Scan';
            app.testboundaryButton = uibutton(app.UIFigure, 'push');
            app.testboundaryButton.ButtonPushedFcn = createCallbackFcn(app, @testboundaryButtonPushed, true);
            app.testboundaryButton.Text = 'testboundary';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_for_lightsheet_V2_test_withfixedpos_withoutcalibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end